classdef curves_map_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CurvesMapAnalyzerbySavoldiTommasoUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        CurvemapLabel                 matlab.ui.control.Label
        LoadMap                       matlab.ui.control.Button
        SlopeDescription              matlab.ui.control.Label
        ForcecurveLabel               matlab.ui.control.Label
        LoadCurve                     matlab.ui.control.Button
        CalibrationLabel              matlab.ui.control.Label
        aunmLabel                     matlab.ui.control.Label
        CalculateSlopeFromFileButton  matlab.ui.control.Button
        SlopeEditField                matlab.ui.control.NumericEditField
        SlopeEditFieldLabel           matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        BackgroundlimitSlider         matlab.ui.control.Slider
        BackgroundlimitSliderLabel    matlab.ui.control.Label
        MainGraph                     matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        TitleEditField                matlab.ui.control.EditField
        TitleEditFieldLabel           matlab.ui.control.Label
        LEditField                    matlab.ui.control.NumericEditField
        LEditFieldLabel               matlab.ui.control.Label
        LLabel                        matlab.ui.control.Label
        ProcessMapButton              matlab.ui.control.Button
        R2_minEditField               matlab.ui.control.NumericEditField
        R2minLabel                    matlab.ui.control.Label
        R2_minLabel                   matlab.ui.control.Label
        nEditField                    matlab.ui.control.NumericEditField
        nLabel_2                      matlab.ui.control.Label
        nLabel                        matlab.ui.control.Label
        nuEditField                   matlab.ui.control.NumericEditField
        nuEditFieldLabel              matlab.ui.control.Label
        nuLabel                       matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        RLabel                        matlab.ui.control.Label
        nmLabel                       matlab.ui.control.Label
        ModelParametersLabel          matlab.ui.control.Label
        kEditField                    matlab.ui.control.NumericEditField
        kLabel                        matlab.ui.control.Label
        NmLabel                       matlab.ui.control.Label
        MapInfoLabel                  matlab.ui.control.Label
        MapinfoLabel                  matlab.ui.control.Label
        MapLoadedLamp                 matlab.ui.control.Lamp
        MaploadedLampLabel            matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        map_filepath = 0;   % Current map loaded filepath
        map_title = 0;      % Current map loaded title
        map_size = 0;       % Current map loaded size in points
        bl_line = 0;        % Background limit line
    end
    
    methods (Access = private)
        
        function [filepath, name, dir]  = pickFile(app)
            [name, dir] = uigetfile('*.*');
            fig = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure;
            if isequal(name, 0)
                uialert(fig, 'File not found.', 'Invalid File', 'Icon', 'error');
                filepath = 0;
            else
                filepath = fullfile(dir, name);
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            addpath ./functions/
            xlim(app.MainGraph, [0 100]);
        end

        % Button pushed function: CalculateSlopeFromFileButton
        function CalculateSlopeFromFileButtonPushed(app, event)
            filepath = pickFile(app);
            fig = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure;
            if ~isequal(filepath, 0)
                % Now that the file has been selected, calculate the slope
                % Load the calibration unload curve
                try
                    [~, ~, zu, Nfu] = load_force_curve(filepath);
                catch
                    % File can not be read. Alert the user
                    uialert(fig, ['File ' filepath ' can not be read. Try removing the first line (it should contain non numeric values).'], 'Valid file', 'Icon', 'error');
                    return
                end
                % Calculate slope starting from 80 % of the max zu to the
                % end and from the top of the curve to the middle point
                slope = calculate_slope(zu, Nfu, 0.80 * max(zu), max(zu));

                % Modify the slopeEditField rounding to the second decimal
                % value
                app.SlopeEditField.Value = round(slope, 2);
                
                % Notify the user
                uialert(fig, ['User selected ' filepath ' which gave a slope of ' num2str(slope) ' au/nm.'], 'Valid file', 'Icon', 'success');
            end
        end

        % Button pushed function: LoadCurve
        function LoadCurveButtonPushed(app, event)
            filepath = pickFile(app);
            fig = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure;
            if ~isequal(filepath, 0)
                % Now that the file has been selected, load the curve
                try
                    [zl, Nfl, zu, Nfu] = load_force_curve(filepath);
                catch
                    % File can not be read. Alert the user
                    uialert(fig, ['File ' filepath ' can not be read. Try removing the first line (it should contain non numeric values).'], 'Valid file', 'Icon', 'error');
                    return
                end
                % Calculate the deflection by dividing by slope value
                dl = Nfl / app.SlopeEditField.Value;
                du = Nfu / app.SlopeEditField.Value;
                % Activate hold on
                hold(app.MainGraph, 'on');
                % Plot the curve on the mainGraph
                plot(app.MainGraph, zl, dl, 'DisplayName', 'load curve');
                plot(app.MainGraph, zu, du, 'DisplayName', 'unload curve');
                % Show legend
                legend(app.MainGraph, 'show');
                % Notify the user
                uialert(fig, ['Curve contained in file ' filepath ' has been successfully loaded.'], 'Valid file', 'Icon', 'success');
            end
        end

        % Button pushed function: ProcessMapButton
        function ProcessMapButtonPushed(app, event)
            fig = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure;
            
            if isequal(app.map_filepath, 0)
                % No map is loaded
                uialert(fig, 'No map is loaded.', 'Fitting process error', 'Icon', 'error');
                return
            end
            
            slope = app.SlopeEditField.Value;
            k = app.kEditField.Value;
            R = app.REditField.Value * 1e-9;
            v = app.nuEditField.Value;
            n = app.nEditField.Value;
            Rsq_min = app.R2_minEditField.Value;
            L = app.LEditField.Value;

            try                
                [Emap, ~] = calculate_E_map(app.map_filepath, slope, k, R, v, n, Rsq_min, 0.80);
                % Show the computed map
                show_E_map(Emap, L, 'Pa', app.TitleEditField.Value);
            catch
                % File can not be read. Alert the user
                uialert(fig, ['File ' app.map_filepath ' can not be read. Try removing the first line (it should contain only four numeric values).'], 'File not valid', 'Icon', 'error');
                return
            end
            
        end

        % Button pushed function: LoadMap
        function LoadMapButtonPushed(app, event)
            fig = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure;
            [filepath, name] = pickFile(app);
            if ~isequal(filepath, 0)
                % Now that the file has been selected, test if it's a valid
                % map file
                try
                    data = load(filepath);
                    % If no exception is thrown it is a valid file
                    % Calculate map size
                    s = size(data);
                    r = s(1);
                    app.map_size = (r - 1);
                    % Remove everything after the dot, so it removes '.txt'
                    app.map_title = name(1:strfind(name,'.')-1);
                    
                    % Update the lamp and text
                    app.MapLoadedLamp.Color = [0, 1, 0]; % Green
                    app.MapInfoLabel.Text = sprintf('Name: %s\nSize: %d pt', app.map_title, app.map_size);
                    if isempty(app.TitleEditField.Value)
                        app.TitleEditField.Value = app.map_title;
                    end
                    app.map_filepath = filepath;
                    drawnow;

                    % Plot on the main graph every curve
                    clf(app.MainGraph);
                    hold(app.MainGraph, 'on');
                    grid(app.MainGraph, 'on');
                    zul = data(1,:)';
                    Nful_list = data(2:r, :)';
                    % zl = zul(1:end/2);
                    z = zul((end/2+1):end);
                    % Nfl_list = Nful_list(1:end/2, :);
                    Nfu_list = Nful_list((end/2+1):end, :);
                    d_list = Nfu_list / app.SlopeEditField.Value;
                    min_curve = z * 0;
                    max_curve = z * 0;
                    for i = 1:10:length(z)
                        min_curve(i) = min(d_list(i, :));
                        max_curve(i) = max(d_list(i, :));
                    end
                    % plot(app.MainGraph, z, max_curve, 'DisplayName', 'maximum values', 'Color', [1, 0, 0]);
                    % plot(app.MainGraph, z, min_curve, 'DisplayName', 'minimum values', 'Color', [0, 0, 1]);
                    clear data;
                catch
                    % Not a valid map file                    
                    app.map_filepath = 0;
                    % Update the lamp and text
                    app.MapLoadedLamp.Color = [1, 0, 0]; % Red
                    app.MapInfoLabel.Text = sprintf('- no info -');
                    drawnow;
                    % Alert the user
                    uialert(fig, ['File ' filepath ' can not be read. Try removing the first line (it should contain only four numeric values).'], 'File not valid', 'Icon', 'error');
                end
            end
        end

        % Value changed function: BackgroundlimitSlider
        function BackgroundlimitSliderValueChanged(app, event)
            value = app.BackgroundlimitSlider.Value;
            
            if ~isequal(app.bl_line, 0)
                % Remove the old line
                delete(app.bl_line);
            end
            app.bl_line = xline(app.MainGraph, value, 'DisplayName', 'Background limit', 'LineStyle','--');
        end

        % Value changing function: BackgroundlimitSlider
        function BackgroundlimitSliderValueChanging(app, event)
            changingValue = event.Value;
            if ~isequal(app.bl_line, 0)
                % Remove the old line
                delete(app.bl_line);
            end
            app.bl_line = xline(app.MainGraph, changingValue, 'DisplayName', 'Background limit', 'LineStyle','--');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure = uifigure('Visible', 'off');
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.Name = 'Curves Map Analyzer - by Savoldi Tommaso';
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CurvesMapAnalyzerbySavoldiTommasoUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SlopeEditFieldLabel = uilabel(app.LeftPanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SlopeEditField.Limits = [1 Inf];
            app.SlopeEditField.ValueDisplayFormat = '%.2f';
            app.SlopeEditField.Value = 1;
            app.CalculateSlopeFromFileButton = uibutton(app.LeftPanel, 'push');
            app.CalculateSlopeFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSlopeFromFileButtonPushed, true);
            app.CalculateSlopeFromFileButton.Text = 'Calculate from file';
            app.aunmLabel = uilabel(app.LeftPanel);
            app.aunmLabel.Text = 'au/nm';
            app.CalibrationLabel = uilabel(app.LeftPanel);
            app.CalibrationLabel.Text = '- Calibration -';
            app.LoadCurve = uibutton(app.LeftPanel, 'push');
            app.LoadCurve.ButtonPushedFcn = createCallbackFcn(app, @LoadCurveButtonPushed, true);
            app.LoadCurve.Text = 'Load single curve';
            app.ForcecurveLabel = uilabel(app.LeftPanel);
            app.ForcecurveLabel.Text = '- Force curve -';
            app.SlopeDescription = uilabel(app.LeftPanel);
            app.SlopeDescription.Text = 'Note: background is fitted on the last 20% of the curve. Slope data is retrieved from z = 0 to -∞.';
            app.LoadMap = uibutton(app.LeftPanel, 'push');
            app.LoadMap.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMap.Text = 'Load curve map';
            app.CurvemapLabel = uilabel(app.LeftPanel);
            app.CurvemapLabel.Text = '- Curve map -';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.MainGraph = uiaxes(app.GridLayout2);
            title(app.MainGraph, 'Force curve')
            xlabel(app.MainGraph, 'z [nm]')
            ylabel(app.MainGraph, 'd [nm]')
            zlabel(app.MainGraph, 'Z')
            app.BackgroundlimitSliderLabel = uilabel(app.GridLayout2);
            app.BackgroundlimitSliderLabel.Text = 'Background limit';
            app.BackgroundlimitSlider = uislider(app.GridLayout2);
            app.BackgroundlimitSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.BackgroundlimitSlider.ValueChangedFcn = createCallbackFcn(app, @BackgroundlimitSliderValueChanged, true);
            app.BackgroundlimitSlider.ValueChangingFcn = createCallbackFcn(app, @BackgroundlimitSliderValueChanging, true);
            app.BackgroundlimitSlider.Tooltip = {'Move the background fit''s limit for all curves of the map. '};
            app.RightPanel = uipanel(app.GridLayout);
            app.MaploadedLampLabel = uilabel(app.RightPanel);
            app.MaploadedLampLabel.Text = 'Map loaded';
            app.MapLoadedLamp = uilamp(app.RightPanel);
            app.MapLoadedLamp.Tooltip = {'Red: no map is loaded'; 'Green: a map is currently loaded and is ready to be processed'};
            app.MapinfoLabel = uilabel(app.RightPanel);
            app.MapinfoLabel.Text = 'Map info:';
            app.MapInfoLabel = uilabel(app.RightPanel);
            app.MapInfoLabel.Interpreter = 'html';
            app.MapInfoLabel.Text = '- no info -';
            app.NmLabel = uilabel(app.RightPanel);
            app.NmLabel.Interpreter = 'latex';
            app.NmLabel.Text = '$N/m$';
            app.kLabel = uilabel(app.RightPanel);
            app.kLabel.Interpreter = 'latex';
            app.kLabel.Text = '$k$';
            app.kEditField = uieditfield(app.RightPanel, 'numeric');
            app.kEditField.Limits = [0 Inf];
            app.kEditField.ValueDisplayFormat = '%.2f';
            app.kEditField.Tooltip = {'Cantilever''s spring constant in N/m.'};
            app.kEditField.Value = 1;
            app.ModelParametersLabel = uilabel(app.RightPanel);
            app.ModelParametersLabel.Text = '- Model parameters -';
            app.nmLabel = uilabel(app.RightPanel);
            app.nmLabel.Interpreter = 'latex';
            app.nmLabel.Text = '$nm$';
            app.RLabel = uilabel(app.RightPanel);
            app.RLabel.Interpreter = 'latex';
            app.RLabel.Text = '$R$';
            app.REditField = uieditfield(app.RightPanel, 'numeric');
            app.REditField.Limits = [0.01 Inf];
            app.REditField.ValueDisplayFormat = '%.2f';
            app.REditField.Tooltip = {'Tip''s radius in nm.'};
            app.REditField.Value = 1;
            app.nuLabel = uilabel(app.RightPanel);
            app.nuLabel.Text = '';
            app.nuEditFieldLabel = uilabel(app.RightPanel);
            app.nuEditFieldLabel.Interpreter = 'latex';
            app.nuEditFieldLabel.Text = '\nu';
            app.nuEditField = uieditfield(app.RightPanel, 'numeric');
            app.nuEditField.Limits = [-1 1];
            app.nuEditField.ValueDisplayFormat = '%.2f';
            app.nuEditField.Tooltip = {'Material''s Poisson''s ratio (has no unity of measure).'};
            app.nuEditField.Value = 0.5;
            app.nLabel = uilabel(app.RightPanel);
            app.nLabel.Interpreter = 'latex';
            app.nLabel.Text = '$pt$';
            app.nLabel_2 = uilabel(app.RightPanel);
            app.nLabel_2.Interpreter = 'latex';
            app.nLabel_2.Text = '$n$';
            app.nEditField = uieditfield(app.RightPanel, 'numeric');
            app.nEditField.Limits = [0 Inf];
            app.nEditField.RoundFractionalValues = 'on';
            app.nEditField.ValueDisplayFormat = '%.0f';
            app.nEditField.Tooltip = {'Moving Average Filter''s size (in number of points).'; 'If it''s 0 the filter is disabled.'};
            app.nEditField.Value = 1;
            app.R2_minLabel = uilabel(app.RightPanel);
            app.R2_minLabel.Text = '';
            app.R2minLabel = uilabel(app.RightPanel);
            app.R2minLabel.Interpreter = 'latex';
            app.R2minLabel.Text = '$R^2_{min}$';
            app.R2_minEditField = uieditfield(app.RightPanel, 'numeric');
            app.R2_minEditField.Limits = [0 1];
            app.R2_minEditField.ValueDisplayFormat = '%.3f';
            app.R2_minEditField.Tooltip = {'Every point fit must have a Rsq value higher than this.'; 'Rsq gives an idea of how good the fit is.'; 'Rsq = 1 means the fit represents 100% of the data.'; ''; 'By putting Rsq min = 0 no point is excluded, even the ones with terrible (Rsq ~ 0) fit.'};
            app.ProcessMapButton = uibutton(app.RightPanel, 'push');
            app.ProcessMapButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessMapButtonPushed, true);
            app.ProcessMapButton.Tooltip = {'Start processing the curve map and show the elastic modulus'' map.'};
            app.ProcessMapButton.Text = 'Process map';
            app.LLabel = uilabel(app.RightPanel);
            app.LLabel.Interpreter = 'latex';
            app.LLabel.Text = '${\mu}m$';
            app.LEditFieldLabel = uilabel(app.RightPanel);
            app.LEditFieldLabel.Interpreter = 'latex';
            app.LEditFieldLabel.Text = '$L$';
            app.LEditField = uieditfield(app.RightPanel, 'numeric');
            app.LEditField.Limits = [0 Inf];
            app.LEditField.ValueDisplayFormat = '%.2f';
            app.LEditField.Tooltip = {'Map''s edge length in micrometers.'};
            app.LEditField.Value = 1;
            app.TitleEditFieldLabel = uilabel(app.RightPanel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.RightPanel, 'text');
            app.TitleEditField.Tooltip = {'Title assigned to the figure.'};
            app.TitleEditField.Placeholder = 'Insert map title...';
            % Show the figure after all components are created
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = curves_map_analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CurvesMapAnalyzerbySavoldiTommasoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CurvesMapAnalyzerbySavoldiTommasoUIFigure)
        end
    end
end