classdef curves_map_analyzer_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CurvesMapAnalyzerbySavoldiTommasoUIFigure  matlab.ui.Figure
        CenterPanelMain               matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        MainTab                       matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        StartScriptButton             matlab.ui.control.Button
        ScriptsDropDown               matlab.ui.control.DropDown
        SelectscriptsfolderButton     matlab.ui.control.Button
        ScriptsLabel                  matlab.ui.control.Label
        RemoveBackground              matlab.ui.control.Button
        SmoothedunloadcurveCheckBox   matlab.ui.control.CheckBox
        GraphoptionsLabel             matlab.ui.control.Label
        LoadMap                       matlab.ui.control.Button
        CurvemapLabel                 matlab.ui.control.Label
        LoadCurve                     matlab.ui.control.Button
        ForcecurveLabel               matlab.ui.control.Label
        SlopeDescription              matlab.ui.control.Label
        CalculateSlopeFromFileButton  matlab.ui.control.Button
        aunmLabel                     matlab.ui.control.Label
        SlopeEditField                matlab.ui.control.NumericEditField
        SlopeEditFieldLabel           matlab.ui.control.Label
        CalibrationLabel              matlab.ui.control.Label
        CenterPanelChild              matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        BackgroundlimitLabel          matlab.ui.control.Label
        BackgroundlimitSlider         matlab.ui.control.Slider
        MainGraph                     matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        SaveDataButton                matlab.ui.control.Button
        ProcessMapButton              matlab.ui.control.Button
        R2_minEditField               matlab.ui.control.NumericEditField
        R2minLabel                    matlab.ui.control.Label
        nLabel                        matlab.ui.control.Label
        nEditField                    matlab.ui.control.NumericEditField
        nLabel_2                      matlab.ui.control.Label
        nuEditField                   matlab.ui.control.NumericEditField
        nuEditFieldLabel              matlab.ui.control.Label
        nmLabel                       matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        RLabel                        matlab.ui.control.Label
        NmLabel                       matlab.ui.control.Label
        LLabel                        matlab.ui.control.Label
        kEditField                    matlab.ui.control.NumericEditField
        kLabel                        matlab.ui.control.Label
        LEditField                    matlab.ui.control.NumericEditField
        LEditFieldLabel               matlab.ui.control.Label
        ModelParametersLabel          matlab.ui.control.Label
        TitleEditField                matlab.ui.control.EditField
        TitleEditFieldLabel           matlab.ui.control.Label
        MapInfoLabel                  matlab.ui.control.Label
        MapinfoLabel                  matlab.ui.control.Label
        MapStatusLamp                 matlab.ui.control.Lamp
        MapstatusLabel                matlab.ui.control.Label
        AnalyzedataTab                matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        GeneratefigurePanel           matlab.ui.container.Panel
        GridLayout17                  matlab.ui.container.GridLayout
        GridLayout19                  matlab.ui.container.GridLayout
        GridLayout20                  matlab.ui.container.GridLayout
        HMapLamp                      matlab.ui.control.Lamp
        Label                         matlab.ui.control.Label
        LoadHMapButton                matlab.ui.control.Button
        umDropDown_2                  matlab.ui.control.DropDown
        umDropDown_2Label             matlab.ui.control.Label
        GridLayout18                  matlab.ui.container.GridLayout
        GeneratefigureButton          matlab.ui.control.Button
        GridLayout17_2                matlab.ui.container.GridLayout
        HistogramCheckBox             matlab.ui.control.CheckBox
        HeightmapCheckBox             matlab.ui.control.CheckBox
        ElasticmodulusmapCheckBox     matlab.ui.control.CheckBox
        OptionsPanel                  matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        GridLayout11_2                matlab.ui.container.GridLayout
        GridLayout16                  matlab.ui.container.GridLayout
        SaveHistogramButton           matlab.ui.control.Button
        GridLayout13_3                matlab.ui.container.GridLayout
        NormalizationDropDown         matlab.ui.control.DropDown
        GridLayout15                  matlab.ui.container.GridLayout
        BinSizeEditField              matlab.ui.control.NumericEditField
        BinsizeEditFieldLabel         matlab.ui.control.Label
        HistogramLabel                matlab.ui.control.Label
        GridLayout11                  matlab.ui.container.GridLayout
        GridLayout14_2                matlab.ui.container.GridLayout
        upperLimitEditField           matlab.ui.control.NumericEditField
        GridLayout14                  matlab.ui.container.GridLayout
        lowerLimitEditField           matlab.ui.control.NumericEditField
        GridLayout13_2                matlab.ui.container.GridLayout
        ResetButton                   matlab.ui.control.Button
        GridLayout13                  matlab.ui.container.GridLayout
        ScaleDropDown                 matlab.ui.control.DropDown
        ScaleLabel                    matlab.ui.control.Label
        GridLayout10                  matlab.ui.container.GridLayout
        GridLayout9_3                 matlab.ui.container.GridLayout
        umDropDown                    matlab.ui.control.DropDown
        umDropDownLabel               matlab.ui.control.Label
        GridLayout9_2                 matlab.ui.container.GridLayout
        LLabelSecondTab               matlab.ui.control.Label
        LEditFieldSecondTab           matlab.ui.control.NumericEditField
        LEditField_2Label             matlab.ui.control.Label
        GridLayout8                   matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        TitleEditFieldSecondTab       matlab.ui.control.EditField
        TitleEditField_2Label         matlab.ui.control.Label
        SelectMapRadioButton          matlab.ui.container.ButtonGroup
        LoadEMapButton                matlab.ui.control.RadioButton
        UseComputedMapButton          matlab.ui.control.RadioButton
        HistAxes                      matlab.ui.control.UIAxes
        EmapAxes                      matlab.ui.control.UIAxes
        PCATab                        matlab.ui.container.Tab
        GridLayout21                  matlab.ui.container.GridLayout
        GridLayout24                  matlab.ui.container.GridLayout
        GridLayout27                  matlab.ui.container.GridLayout
        LoadPCAButton                 matlab.ui.control.Button
        ExecutePCAButton              matlab.ui.control.Button
        GridLayout25_2                matlab.ui.container.GridLayout
        LoadPeaksTableButton          matlab.ui.control.Button
        PeaksTableLamp                matlab.ui.control.Lamp
        GridLayout25                  matlab.ui.container.GridLayout
        GridLayout26                  matlab.ui.container.GridLayout
        SavePeaksTableButton          matlab.ui.control.Button
        ExtractpeakstableButton       matlab.ui.control.Button
        GridLayout22                  matlab.ui.container.GridLayout
        GridLayout23                  matlab.ui.container.GridLayout
        HeightMapAxes                 matlab.ui.control.UIAxes
        PCAxes                        matlab.ui.control.UIAxes
        SpectrumAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Variables starting with h_ are handles to the plot
        
        map_filepath = 0;       % Current map loaded filepath
        map_title = 0;          % Current map loaded title
        map_size = 0;           % Current map loaded size in points
        map_Emap = 0;           % Current map loaded Emap processed
        h_bl_line = 0;          % Background limit line
        h_smoothed_curve = 0;   % Smoothed curve
        shown_curve_l = 0;      % The load curve currently being shown
        shown_curve_u = 0;      % The unload curve currently being shown
        h_shown_curve_l = 0;    % The load curve currently being shown
        h_shown_curve_u = 0;    % The unload curve currently being shown
        
        plotted_curves = [];    % List that keeps track of the curves being plotted

        scripts_folder = 0;     % Path to the folder inside which are stored the scripts to be executed
        scripts_list = [];      % List containing all the available scripts
        
        recent_path = 0;        % Most recent path visited while picking files

        can_update_axes = true;

        % Costants
        NO_MAP_COLOR        = [1, 0, 0];
        LOADED_MAP_COLOR    = [0.92, 0.66, 0.20];
        PROCESSED_MAP_COLOR = [0, 1, 0];
    end
    
    properties (Access = public)
        Emap = 0;                   % Current Emap loaded
        displayed_Emap = 0;         % Current map being displayed in the analyze tab
        h_displayed_histogram = 0;  % Handle to the current histogram being displayed
        displayed_Hmap = 0;         % Current height map being displayed in the analyze tab

        peaks_table = 0;            % Current peaks table loaded
        peaks = 0;                  % Peaks vector of the peaks table
        peaks_table_x = 0;          % Peaks table x and y vectors
        peaks_table_y = 0;          
    end
    
    methods (Access = private)
        
        function [filepath, name, dirname]  = pickFile(app)
            if isequal(app.recent_path, 0)
                [name, dirname] = uigetfile('*.*');
            else
                [name, dirname] = uigetfile([app.recent_path '\*.*']);
            end
            
            if isequal(name, 0)
                app.alert('Invalid File', 'File not found.', 'error');
                filepath = 0;
                return
            end

            filepath = fullfile(dirname, name);
            app.recent_path = dirname;
        end

        function path  = pickDir(app, title)
            if ~isequal(app.recent_path, 0)
                path = uigetdir(app.recent_path, title);
            else
                path = uigetdir(pwd, title);
            end

            if isequal(path, 0)
                app.alert('Invalid Directory', 'Directory not found.', 'error');
                return;
            end

            app.recent_path = path;
        end
        
        function alert(app, title, text, icon)
            fig = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure;
            uialert(fig, text, title, 'Icon', icon);
        end
        
        function id = plot_curve(app, x, y, displayName, color, style)
            if isequal(color, 0)
                h = plot(app.MainGraph, x, y, 'DisplayName', displayName, 'LineStyle', style);
            else
                h = plot(app.MainGraph, x, y, 'DisplayName', displayName, 'Color', color, 'LineStyle', style);
            end
            
            % Create curve obj
            % It consists of
            % h = handle
            % x, y, displayName, color, style = plot options
            curve = {h, x, y, displayName, color, style};
            
            id = length(app.plotted_curves);
            app.plotted_curves = [app.plotted_curves, curve];
        end
        
        function clear_plot(app)
            clf(app.MainGraph);
            app.plotted_curves = [];
        end
        
        function erase_curve(app, id)
            curve = app.plotted_curves(id);
            delete(curve{1});
            app.plotted_curves(id) = 0;
        end
        
        function modify_curve_data(app, id, x, y)
            curve = app.plotted_curves(id);
            delete(curve{1});

            if isequal(curve{5}, 0)
                h = plot(app.MainGraph, x, y, 'DisplayName', curve{4}, 'LineStyle', curve{6});
            else
                h = plot(app.MainGraph, x, y, 'DisplayName', curve{4}, 'Color', curve{5}, 'LineStyle', curve{6});
            end
            
            curve{1} = h;
            curve{2} = x;
            curve{3} = y;

            app.plotted_curves(id) = curve;
        end
        
        function load_computed_map(app)
            if isequal(app.Emap, 0)
                app.alert('No map present', 'No map has been yet computed. Compute a map first.', 'error');
                return
            end
            
            app.TitleEditFieldSecondTab.Value = app.TitleEditField.Value;
            app.LEditFieldSecondTab.Value = app.LEditField.Value;
            app.display_Emap(app.Emap);
        end
        
        function load_external_map(app)
            [filepath, name, dir] = app.pickFile();
            
            try
                data = load(filepath);
            catch
                app.alert('Error loading map', 'The file selected contains an invalid map. Please try again.', 'error');
                app.SelectMapRadioButton.SelectedObject = app.UseComputedMapButton;
                return
            end
            
            s = size(data);
            if s(1) ~= s(2)
                app.alert('Error loading map', 'The file selected contains an invalid map. Please try again.', 'error');
                app.SelectMapRadioButton.SelectedObject = app.UseComputedMapButton;
                return
            end
            
            % Remove extension before setting map's name
            app.TitleEditFieldSecondTab.Value = name(1:strfind(name,'.')-1);
            app.display_Emap(data);
        end
        
        function display_Emap(app, Emap)
            if isequal(Emap, 0)
                app.alert('Empty map', 'Tried to display an empty map.', 'error');
                return
            end

            app.displayed_Emap = Emap;
            
            emap = app.displayed_Emap;
            ax = app.EmapAxes;
            tit = app.TitleEditFieldSecondTab.Value;
            L = app.LEditFieldSecondTab.Value;
            um = app.umDropDown.Value;
            lims = [app.lowerLimitEditField.Value, app.upperLimitEditField.Value];
            scale = app.ScaleDropDown.Value;
            factor = app.get_factor(um);
            
            add_E_map_to_figure(emap * factor, 'axes', ax, 'title', tit, 'fullLegend', false, 'L', L, 'um', um, 'clim', lims, 'scale', scale);

            lims = get(ax, 'CLim');
            app.lowerLimitEditField.Value = lims(1);
            app.upperLimitEditField.Value = lims(2);

            app.display_histogram;
        end
        
        function display_histogram(app)
            % Remove histogram
            cla(app.HistAxes);

            if isequal(app.displayed_Emap, 0)
                app.SaveHistogramButton.Enable = 'off';
                return
            end
            
            um = app.umDropDown.Value;
            factor = app.get_factor(um);
            emap = app.displayed_Emap * factor;
            s = app.BinSizeEditField.Value;
            lims = [app.lowerLimitEditField.Value, app.upperLimitEditField.Value];
            norm = app.NormalizationDropDown.Value;

            if s ~= 0
                h = histogram(app.HistAxes, emap, 'BinWidth', s, 'Normalization', norm);
            else
                h = histogram(app.HistAxes, emap, 'Normalization', norm);
            end

            xlim(app.HistAxes, lims);
            xlabel(app.HistAxes, ['E [' um ']']);
            ylabel(app.HistAxes, norm);
            grid(app.HistAxes, 'on');

            app.SaveHistogramButton.Enable = 'on';
            app.h_displayed_histogram = h;
        end
        
        function factor = get_factor(app, um)
            switch um
                case 'mPa'
                    factor = 1e3;
                case 'kPa'
                    factor = 1e-3;
                case 'MPa'
                    factor = 1e-6;
                case 'GPa'
                    factor = 1e-9;
                otherwise
                    factor = 1;
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath functions/
            addpath more-colormaps/
            xlim(app.MainGraph, [0 100]);
            hold(app.MainGraph, 'on');
            grid(app.MainGraph, 'on');
            legend(app.MainGraph, 'show', 'Location', 'best');
        end

        % Button pushed function: CalculateSlopeFromFileButton
        function CalculateSlopeFromFileButtonPushed(app, event)
            filepath = pickFile(app);
            if ~isequal(filepath, 0)
                % Now that the file has been selected, calculate the slope
                % Load the calibration unload curve
                try
                    [~, ~, zu, Nfu] = load_force_curve(filepath);
                catch
                    % File can not be read. Alert the user
                    app.alert('Valid file', ['File ' filepath ' can not be read. Try removing the first line (it should contain non numeric values).'], 'error');
                    return
                end
                % Calculate slope starting from 80 % of the max zu to the
                % end and from the top of the curve to the middle point
                slope = calculate_slope(zu, Nfu, 0.80 * max(zu), max(zu));

                % Modify the slopeEditField rounding to the second decimal
                % value
                app.SlopeEditField.Value = round(slope, 2);
                
                % Notify the user
                app.alert('Valid file', ['User selected ' filepath ' which gave a slope of ' num2str(slope) ' au/nm.'], 'success');
            end
        end

        % Button pushed function: LoadCurve
        function LoadCurveButtonPushed(app, event)
            filepath = pickFile(app);
            if ~isequal(filepath, 0)
                % Now that the file has been selected, load the curve
                try
                    [zl, Nfl, zu, Nfu] = load_force_curve(filepath);
                catch
                    % File can not be read. Alert the user
                    app.alert('Valid file', ['File ' filepath ' can not be read. Try removing the first line (it should contain non numeric values).'], 'error');
                    return
                end
                % Activate hold on
                hold(app.MainGraph, 'on');
                % Plot the curve on the mainGraph
                % Calculate the deflection by dividing by slope value
                slope = app.SlopeEditField.Value;
                app.h_shown_curve_l = plot(app.MainGraph, zl, Nfl / slope, 'DisplayName', 'load curve', 'Color', 'r');
                app.h_shown_curve_u = plot(app.MainGraph, zu, Nfu / slope, 'DisplayName', 'unload curve', 'Color', 'b');
                % Show legend
                legend(app.MainGraph, 'show');
                % Notify the user
                app.alert('Valid file', ['Curve contained in file ' filepath ' has been successfully loaded.'], 'success');

                app.shown_curve_l = [zl, Nfl];
                app.shown_curve_u = [zu, Nfu];
            end
        end

        % Button pushed function: ProcessMapButton
        function ProcessMapButtonPushed(app, event)
            if isequal(app.map_filepath, 0)
                % No map is loaded
                app.alert('Fitting process error', 'No map is loaded.', 'error');
                return
            end
            
            slope = app.SlopeEditField.Value;
            k = app.kEditField.Value;
            R = app.REditField.Value * 1e-9;
            v = app.nuEditField.Value;
            n = app.nEditField.Value;
            Rsq_min = app.R2_minEditField.Value;
            L = app.LEditField.Value;
            b_start = app.BackgroundlimitSlider.Value / 100; 

            try                
                [app.Emap, ~] = calculate_E_map(app.map_filepath, slope, k, R, v, n, Rsq_min, b_start);
            catch
                % File can not be read. Alert the user
                app.alert('File not valid', ['File ' app.map_filepath ' can not be read. Try removing the first line (it should contain only four numeric values).'], 'error');
                return
            end
            
            % Show the computed map
            show_E_map(app.Emap, L, 'Pa', app.TitleEditField.Value);
            app.map_Emap = app.Emap;
            % Update the lamp
            app.MapStatusLamp.Color = app.PROCESSED_MAP_COLOR;
            % Enable save data button
            app.SaveDataButton.Enable = "on";
        end

        % Button pushed function: LoadMap
        function LoadMapButtonPushed(app, event)
            [filepath, name] = pickFile(app);
            if ~isequal(filepath, 0)
                % Now that the file has been selected, test if it's a valid
                % map file
                try
                    data = load_E_map(filepath);
                    
                    % If no exception is thrown it is a valid file
                    % Calculate map size
                    s = size(data);
                    r = s(1);
                    app.map_size = (r - 1);
                    % Remove everything after the dot, so it removes '.txt'
                    app.map_title = name(1:strfind(name,'.')-1);
                    
                    % Update the lamp and text
                    app.MapStatusLamp.Color = app.LOADED_MAP_COLOR;
                    app.MapInfoLabel.Text = sprintf('Name: %s\nSize: %d pt', app.map_title, app.map_size);
                    if isempty(app.TitleEditField.Value)
                        app.TitleEditField.Value = app.map_title;
                    end
                    app.map_filepath = filepath;
                    drawnow;

                    % Enable process button
                    app.ProcessMapButton.Enable = "on";
                    % Disable save data
                    app.SaveDataButton.Enable = "off";

                    % Plot on the main graph some curves
                    cla(app.MainGraph);
                    hold(app.MainGraph, 'on');
                    grid(app.MainGraph, 'on');
                    legend(app.MainGraph, 'show', 'Location', 'best');
                    zul = data(1,:)';
                    Nful_list = data(2:r, :)';
                    % zl = zul(1:end/2);
                    z = zul((end/2+1):end);
                    % Nfl_list = Nful_list(1:end/2, :);
                    Nfu_list = Nful_list((end/2+1):end, :);
                    d_list = Nfu_list / app.SlopeEditField.Value;
                    mean_curve = mean(d_list, 2);
                    mode_curve = mode(d_list, 2);
                    median_curve = median(d_list, 2);
                    plot(app.MainGraph, z, mean_curve, 'DisplayName', 'mean curve');
                    plot(app.MainGraph, z, mode_curve, 'DisplayName', 'mode curve');
                    plot(app.MainGraph, z, median_curve, 'DisplayName', 'median curve');
                    clear data;
                catch
                    % Not a valid map file                    
                    app.map_filepath = 0;
                    % Update the lamp and text
                    app.MapStatusLamp.Color = app.NO_MAP_COLOR;
                    app.MapInfoLabel.Text = sprintf('- no info -');
                    drawnow;
                    % Disable process button
                    app.ProcessMapButton.Enable = "off";
                    % Disable save data button
                    app.SaveDataButton.Enable = "off";
                    % Alert the user
                    app.alert('File not valid', ['File ' filepath ' can not be read. Try removing the first line (it should contain only four numeric values).'], 'error');
                end
            end
        end

        % Value changed function: BackgroundlimitSlider
        function BackgroundlimitSliderValueChanged(app, event)
            value = app.BackgroundlimitSlider.Value;
            
            if ~isequal(app.h_bl_line, 0)
                % Remove the old line
                delete(app.h_bl_line);
            end
            app.h_bl_line = xline(app.MainGraph, value, 'DisplayName', 'Background limit', 'LineStyle','--');
        end

        % Value changing function: BackgroundlimitSlider
        function BackgroundlimitSliderValueChanging(app, event)
            changingValue = event.Value;
            if ~isequal(app.h_bl_line, 0)
                % Remove the old line
                delete(app.h_bl_line);
            end
            app.h_bl_line = xline(app.MainGraph, changingValue, 'DisplayName', 'Background limit', 'LineStyle','--');
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            
            % Update the curve if it is being shown
            if app.SmoothedunloadcurveCheckBox.Value
                % Calculate the smooth curve
                [smooth_z, smooth_Nf] = moving_average_filter(app.shown_curve_u(:, 1), app.shown_curve_u(:, 2), value);
                
                % Remove the old one (only if it exists) and replace it with the new one
                if ~isequal(app.h_smoothed_curve, 0)
                    delete(app.h_smoothed_curve);
                end
                
                app.h_smoothed_curve = plot(app.MainGraph, smooth_z, smooth_Nf / app.SlopeEditField.Value, 'DisplayName', 'smoothed curve', 'Color', 'cyan', 'LineStyle', '-.');
            end
        end

        % Value changed function: SmoothedunloadcurveCheckBox
        function SmoothedunloadcurveCheckBoxValueChanged(app, event)
            value = app.SmoothedunloadcurveCheckBox.Value;
            
            if value && ~isequal(app.shown_curve_u, 0)
                % Show the curve
                
                % Calculate the smooth curve
                [smooth_z, smooth_Nf] = moving_average_filter(app.shown_curve_u(:, 1), app.shown_curve_u(:, 2), app.nEditField.Value);

                app.h_smoothed_curve = plot(app.MainGraph, smooth_z, smooth_Nf / app.SlopeEditField.Value, 'DisplayName', 'smoothed curve', 'Color', 'cyan', 'LineStyle', '-.');
            else
                if ~isequal(app.h_smoothed_curve, 0)
                    % Only if it exists
                    delete(app.h_smoothed_curve);
                end
            end
        end

        % Value changed function: SlopeEditField
        function SlopeEditFieldValueChanged(app, event)
            value = app.SlopeEditField.Value;
            
            % Update shown curve
            if ~isequal(app.shown_curve_l, 0)
                if ~isequal(app.h_shown_curve_l, 0)
                    delete(app.h_shown_curve_l);
                end
                app.h_shown_curve_l = plot(app.MainGraph, app.shown_curve_l(:, 1), app.shown_curve_l(:, 2) / value, 'DisplayName', 'load curve', 'Color', 'r');
            end
            
            if ~isequal(app.shown_curve_u, 0)
                if ~isequal(app.h_shown_curve_u, 0)
                    delete(app.h_shown_curve_u);
                end
                app.h_shown_curve_u = plot(app.MainGraph, app.shown_curve_u(:, 1), app.shown_curve_u(:, 2) / value, 'DisplayName', 'unload curve', 'Color', 'b');
            end
            
            if app.SmoothedunloadcurveCheckBox.Value && ~isequal(app.h_smoothed_curve, 0)
                delete(app.h_smoothed_curve);
                
                [smooth_z, smooth_Nf] = moving_average_filter(app.shown_curve_u(:, 1), app.shown_curve_u(:, 2), app.nEditField.Value);
                app.h_smoothed_curve = plot(app.MainGraph, smooth_z, smooth_Nf / app.SlopeEditField.Value, 'DisplayName', 'smoothed curve', 'Color', 'cyan', 'LineStyle', '-.');
            end
        end

        % Button pushed function: RemoveBackground
        function RemoveBackgroundButtonPushed(app, event)
            b_start = app.BackgroundlimitSlider.Value / 100;
            slope = app.SlopeEditField.Value;
            if ~isequal(app.shown_curve_l, 0)
                if ~isequal(app.h_shown_curve_l, 0)
                    delete(app.h_shown_curve_l);
                end
                z = app.shown_curve_l(:, 1);
                Nf = app.shown_curve_l(:, 2);
                Nf = remove_background(z, Nf, max(z) * b_start, Inf);
                app.shown_curve_l = [z, Nf];

                app.h_shown_curve_l = plot(app.MainGraph, z, Nf/slope, 'DisplayName', 'load curve', 'Color', 'r');
            end
            if ~isequal(app.shown_curve_u, 0)
                if ~isequal(app.h_shown_curve_u, 0)
                    delete(app.h_shown_curve_u);
                end
                z = app.shown_curve_u(:, 1);
                Nf = app.shown_curve_u(:, 2);
                Nf = remove_background(z, Nf, max(z) * b_start, Inf);
                app.shown_curve_u = [z, Nf];

                app.h_shown_curve_u = plot(app.MainGraph, z, Nf/slope, 'DisplayName', 'unload curve', 'Color', 'b');
            end
            if app.SmoothedunloadcurveCheckBox.Value && ~isequal(app.shown_curve_u, 0)
                if ~isequal(app.h_smoothed_curve, 0)
                    delete(app.h_smoothed_curve);
                end
                z = app.shown_curve_u(:, 1);
                Nf = app.shown_curve_u(:, 2);
                [z, Nf] = moving_average_filter(z, Nf, app.nEditField.Value);
                Nf = remove_background(z, Nf, max(z) * b_start, Inf);
   
                app.h_smoothed_curve = plot(app.MainGraph, z, Nf/slope, 'DisplayName', 'smoothed curve', 'Color', 'cyan', 'LineStyle', '-.');
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            if isequal(app.map_Emap, 0)
                app.alert('No map has been processed', 'You must process the map first.', 'error');
                return
            end
            
            try
                [fileName, pathName] = uiputfile({'*.csv'; '*.tsv'; '*.txt'; '*.bin'}, 'Please select a file location', [app.TitleEditField.Value '.csv']);
                writematrix(app.map_Emap, fullfile(pathName, fileName));
            catch
                app.alert('Error', 'An error occured while saving the file. Data has not been saved.', 'error');
                return
            end

            app.alert('File saved', ['Data has been saved successfully as ' fileName '.'], 'success');
        end

        % Button pushed function: SelectscriptsfolderButton
        function SelectscriptsfolderButtonPushed(app, event)
            path = pickDir(app);

            if isequal(path, 0)
                % Do not change the current path nor the selector list
                return
            end

            app.scripts_folder = path;

            % Find all available scripts
            files = dir(app.scripts_folder);
            app.scripts_list = {};

            for i = 1:length(files)
                name = files(i).name;
                % Check if the extension is '.m'
                if length(name) > 2 && strcmp(name(end-1:end), '.m')
                    app.scripts_list{end+1} = name(1:end-2);
                end
            end

            % Update the selector
            app.ScriptsDropDown.Items = app.scripts_list;
            app.ScriptsDropDown.Enable = 'on';

            % Enable start button only if there are scripts available
            app.StartScriptButton.Enable = ~isempty(app.scripts_list);
            
        end

        % Drop down opening function: ScriptsDropDown
        function ScriptsDropDownOpening(app, event)
            % Update the selector
            % Find all available scripts
            files = dir(app.scripts_folder);
            app.scripts_list = {};
            for i = 1:length(files)
                name = files(i).name;
                % Check if the extension is '.m'
                if length(name) > 2 && strcmp(name(end-1:end), '.m')
                    app.scripts_list{end+1} = name(1:end-2);
                end
            end
            app.ScriptsDropDown.Items = app.scripts_list;
            app.StartScriptButton.Enable = ~isempty(app.scripts_list);
        end

        % Button pushed function: StartScriptButton
        function StartScriptButtonPushed(app, event)
            run(fullfile(app.scripts_folder, [app.ScriptsDropDown.Value '.m']));
        end

        % Selection changed function: SelectMapRadioButton
        function SelectMapRadioButtonSelectionChanged(app, event)
            selectedButton = app.SelectMapRadioButton.SelectedObject;
            
            app.can_update_axes = false;
            if selectedButton == app.LoadEMapButton
                app.load_external_map();
            elseif selectedButton == app.UseComputedMapButton
                app.load_computed_map();
            end
            app.can_update_axes = true;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.AnalyzedataTab
                app.SelectMapRadioButtonSelectionChanged(0);
            end
        end

        % Value changed function: TitleEditFieldSecondTab
        function TitleEditFieldSecondTabValueChanged(app, event)
            if app.can_update_axes
                app.display_Emap(app.displayed_Emap);
            end
        end

        % Value changed function: LEditFieldSecondTab
        function LEditFieldSecondTabValueChanged(app, event)
            if app.can_update_axes
                app.display_Emap(app.displayed_Emap);
            end
        end

        % Value changed function: umDropDown
        function umDropDownValueChanged(app, event)
            if app.can_update_axes
                % Reset limits first
                app.can_update_axes = false;
                app.lowerLimitEditField.Value = 0;
                app.upperLimitEditField.Value = 0;
                app.can_update_axes = true;

                app.display_Emap(app.displayed_Emap);
            end
        end

        % Value changed function: lowerLimitEditField
        function lowerLimitEditFieldValueChanged(app, event)
            if app.can_update_axes
                app.display_Emap(app.displayed_Emap);
            end
        end

        % Value changed function: upperLimitEditField
        function upperLimitEditFieldValueChanged(app, event)
            if app.can_update_axes
                app.display_Emap(app.displayed_Emap);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if app.can_update_axes
                app.can_update_axes = false;
                app.lowerLimitEditField.Value = 0;
                app.upperLimitEditField.Value = 0;
                app.can_update_axes = true;
                
                app.display_Emap(app.displayed_Emap);
            end
        end

        % Value changed function: ScaleDropDown
        function ScaleDropDownValueChanged(app, event)
            if app.can_update_axes
                app.display_Emap(app.displayed_Emap);
            end
        end

        % Value changed function: NormalizationDropDown
        function NormalizationDropDownValueChanged(app, event)
            if app.can_update_axes
                app.display_histogram;
            end
        end

        % Value changed function: BinSizeEditField
        function BinSizeEditFieldValueChanged(app, event)
            if app.can_update_axes
                app.display_histogram;
            end
        end

        % Button pushed function: SaveHistogramButton
        function SaveHistogramButtonPushed(app, event)
            if isequal(app.h_displayed_histogram, 0)
                app.alert('No histogram has been generated', 'You must generate the histogram first.', 'error');
                return
            end
            
            try
                [fileName, pathName] = uiputfile({'*.csv'; '*.tsv'; '*.txt'; '*.bin'}, 'Please select a file location', [app.TitleEditField.Value '.csv']);

                edges = app.h_displayed_histogram.BinEdges;
                values = app.h_displayed_histogram.Values;
                
                centers = 0 * values;
                for i = 1:length(centers)
                    centers(i) = (edges(i) + edges(i + 1)) / 2;
                end

                data = [centers; values]';

                writematrix(data, fullfile(pathName, fileName));
            catch
                app.alert('Error', 'An error occured while saving the file. Data has not been saved.', 'error');
                return
            end

            app.alert('File saved', ['Data has been saved successfully as ' fileName '.'], 'success');
        end

        % Button pushed function: GeneratefigureButton
        function GeneratefigureButtonPushed(app, event)
            s = app.ElasticmodulusmapCheckBox.Value + app.HeightmapCheckBox.Value + app.HistogramCheckBox.Value;
            
            if s == 0
                app.alert('No item selected', 'No item has been selected. Please select at least one item to show on the figure.', 'error');
                return
            end

            fig = figure;
            
            if s == 3
                fig.Position(3) = fig.Position(3) * 2;
                fig.Position(4) = fig.Position(4) * 2;
                tiledlayout(2, 2);
            elseif s == 2
                fig.Position(3) = fig.Position(3) * 2;
                tiledlayout(1, 2);
            else
                tiledlayout(1, 1);
            end

            if app.HeightmapCheckBox.Value
                % Load an height map first
                [filepath, name, dir] = app.pickFile();

                try
                    Hmap = load_height_map(filepath);

                    nexttile;
                    ax = gca;
                    tit = sprintf('Height map - %s', app.TitleEditFieldSecondTab.Value);
                    L = app.LEditFieldSecondTab.Value;
                    um = app.umDropDown_2.Value;
                    if isequal(um, 'um')
                        um = '{\mu}m';
                    end
                    % factor = app.get_factor(um);

                    add_height_map_to_figure(Hmap, 'L', L, 'um', um, 'title', tit, 'axes', ax, 'cmap', slanCM('heat'));
                catch
                    app.alert('Error loading map', 'The file selected contains an invalid map. Please try again.', 'error');
                    return
                end
            end

            if app.ElasticmodulusmapCheckBox.Value
                if isequal(app.displayed_Emap, 0)
                    app.alert('Map not existent', 'No map is currently being shown. Load a map first.', 'error');
                    return
                end

                nexttile;
                ax = gca;
                tit = sprintf('E map - %s', app.TitleEditFieldSecondTab.Value);
                L = app.LEditFieldSecondTab.Value;
                um = app.umDropDown.Value;
                lims = [app.lowerLimitEditField.Value, app.upperLimitEditField.Value];
                scale = app.ScaleDropDown.Value;
                factor = app.get_factor(um);

                add_E_map_to_figure(app.displayed_Emap * factor, 'axes', ax, 'title', tit, 'fullLegend', true, 'L', L, 'um', um, 'clim', lims, 'scale', scale);
            end

            if app.HistogramCheckBox.Value
                if isequal(app.displayed_Emap, 0)
                    app.alert('Map not existent', 'No map is currently being shown. Load a map first.', 'error');
                    return
                end

                nexttile;
                ax = gca;
                um = app.umDropDown.Value;
                factor = app.get_factor(um);
                emap = app.displayed_Emap * factor;
                s = app.BinSizeEditField.Value;
                lims = [app.lowerLimitEditField.Value, app.upperLimitEditField.Value];
                norm = app.NormalizationDropDown.Value;
                tit = sprintf('Histogram - %s', app.TitleEditFieldSecondTab.Value);
                
                if s ~= 0
                    histogram(ax, emap, 'BinWidth', s, 'Normalization', norm);
                else
                    histogram(ax, emap, 'Normalization', norm);
                end

                xlim(ax, lims);
                xlabel(ax, ['E [' um ']']);
                ylabel(ax, norm);
                title(ax, tit);
                grid(ax, 'on');
            end
        end

        % Button pushed function: LoadHMapButton
        function LoadHMapButtonPushed(app, event)
            try
                Hmap = load_height_map(filepath);
            catch
                app.alert('Error loading map', 'The file selected contains an invalid map. Please try again.', 'error');
                return
            end
            
            app.displayed_Hmap = Hmap;
            app.HMapLamp.Color = app.PROCESSED_MAP_COLOR;
        end

        % Button pushed function: ExtractpeakstableButton
        function ExtractpeakstableButtonPushed(app, event)
            peaks_dir = app.pickDir('Select the directory containing the files generated by LabSpec');
            
            if isequal(peaks_dir, 0)
                app.alert('Invalid path', 'No valid path was selected.', 'error');
                return
            end

            % Open all the files
            peaks_files = dir(fullfile(peaks_dir, '*.txt'));

            % Create the peaks table
            % It will be a matrix where the rows represent the points of the map,
            % and the columns contain the detected peaks along with their respective amplitudes.
            % The row vector 'peaks' stores the center of the detected peaks.
            % The matrix 'peaks_table' lists, one below the other, the row vector containing
            % the amplitude of each peak for every point on the map.

            app.peaks = zeros(1, length(peaks_files));
            app.peaks_table = zeros(1, length(peaks_files));

            fprintf('Found %d peaks\n', length(peaks_files));
            for i = 1:length(peaks_files)
                fprintf('Processing file %d/%d\n', i, length(peaks_files));
                f = peaks_files(i);

                % Open the file and get the Raman map. This will contain
                % only one peak
                temp1file = 'temp1file.tsv';
                temp2file = 'temp2file.tsv';

                % Work with the file temp1 only if it is the first file being processed,  
                % to avoid repeatedly reading the same shifts.
                if i == 1
                    fid_temp1 = fopen(temp1file, 'w');
                end
                fid_temp2 = fopen(temp2file, 'w');

                fid = fopen(fullfile(peaks_dir, f.name), 'r');
                % Check that it is not empty
                if feof(fid)
                    return
                end

                line = fgetl(fid);
                if i == 1
                    fprintf(fid_temp1, '%s\n', line);
                end

                while ~feof(fid)
                    line = fgetl(fid);
                    if ischar(line)
                        fprintf(fid_temp2, '%s\n', line);
                    end
                end

                fclose(fid);
                if i == 1
                    fclose(fid_temp1);
                end
                fclose(fid_temp2);

                if i == 1
                    shift = load(temp1file);
                end
                data = load(temp2file);

                if i == 1
                    app.peaks_table_x = data(:, 1);
                    app.peaks_table_y = data(:, 2);
                end
                intensities = data(:, 3:end);

                % Now detect the peak
                % Find the maximum for each spectrum and save the shift and intensity
                % Remember to transpose; otherwise, it will be incorrect.
                [temp_peaks, temp_peaks_i] = max(intensities');
                % Choose the peak corresponding to the highest peak present in the map.  
                [~, p] = max(temp_peaks);
                temp_peak_i = temp_peaks_i(p);
                % Take shift and amp for every point
                sft = shift(temp_peak_i);
                amp = intensities(:, temp_peak_i);

                % Add to the peaks_table
                app.peaks(i) = sft;

                % Initialize the peaks_table if needed
                if i == 1
                    app.peaks_table = zeros(length(amp), length(app.peaks));
                end
                app.peaks_table(:, i) = amp;
            end

            fprintf("Peaks found in the map (they are not sorted, LabSpec's fault):\n");
            for i = 1:length(app.peaks)
                fprintf('\t%5.2f cm^-1\n', app.peaks(i));
            end

            % Show the first 3 peaks
            cla(app.SpectrumAxes);
            grid(app.SpectrumAxes, 'on');
            hold(app.SpectrumAxes, 'on');
            stem(app.SpectrumAxes, app.peaks, app.peaks_table(1, :), 'filled', 'DisplayName', 'Spectrum 1', 'LineWidth', 2);
            stem(app.SpectrumAxes, app.peaks, app.peaks_table(2, :), 'filled', 'DisplayName', 'Spectrum 2', 'LineWidth', 2);
            stem(app.SpectrumAxes, app.peaks, app.peaks_table(3, :), 'filled', 'DisplayName', 'Spectrum 3', 'LineWidth', 2);
            legend(app.SpectrumAxes, 'show', 'Location', 'best');
            title(app.SpectrumAxes, 'First 3 spectra of the map');
            xlabel(app.SpectrumAxes, 'Raman shift [cm^{-1}]');
            ylabel(app.SpectrumAxes, 'intensity [au]');

            app.SavePeaksTableButton.Enable = 'on';
            app.PeaksTableLamp.Color = app.LOADED_MAP_COLOR;
            app.ExecutePCAButton.Enable = 'on';
        end

        % Button pushed function: SavePeaksTableButton
        function SavePeaksTableButtonPushed(app, event)
            if isequal(app.peaks_table, 0)
                app.alert('No peaks table has been extracted', 'You must extract a peaks table first.', 'error');
                return
            end
            
            try
                if isequal(app.recent_path, 0)
                    [filename, dirname] = uiputfile({'*.txt'; '*.csv'; '*.tsv'; '*.bin'}, 'Please select a file location', 'peaks_table.txt');
                else
                    [filename, dirname] = uiputfile({'*.txt'; '*.csv'; '*.tsv'; '*.bin'}, 'Please select a file location', [app.recent_path '\peaks_table.txt']);
                end

                writematrix(app.peaks, fullfile(dirname, filename));
                data = [app.peaks_table_x, app.peaks_table_y, app.peaks_table];
                writematrix(data, fullfile(dirname, filename), 'WriteMode','append');

                app.recent_path = dirname;
            catch
                app.alert('Error', 'An error occured while saving the file. Data has not been saved.', 'error');
                return
            end

            app.alert('File saved', ['Data has been saved successfully as ' filename '.'], 'success');
        end

        % Button pushed function: LoadPeaksTableButton
        function LoadPeaksTableButtonPushed(app, event)
            filepath = app.pickFile();
            if isequal(filepath, 0)
                app.alert('Invalid file', 'No peaks table was loaded.', 'error');
                return
            end
            
            try
                temp1file = 'temp1file.tsv';
                temp2file = 'temp2file.tsv';

                fid_temp1 = fopen(temp1file, 'w');
                fid_temp2 = fopen(temp2file, 'w');

                fid = fopen(filename, 'r');

                if feof(fid)
                    return
                end

                line = fgetl(fid);
                fprintf(fid_temp1, '%s\n', line);

                while ~feof(fid)
                    line = fgetl(fid);
                    if ischar(line)
                        fprintf(fid_temp2, '%s\n', line);
                    end
                end

                fclose(fid);
                fclose(fid_temp1);
                fclose(fid_temp2);
                clear line;

                app.peaks = load(temp1file);
                data = load(temp2file);

                app.peaks_table_x = data(:, 1);
                app.peaks_table_y = data(:, 2);
                app.peaks_table = data(:, 3:end);
            catch
                app.alert('Failed to load the peaks table', 'An error has occured while loading the peaks table.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure = uifigure('Visible', 'off');
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.Name = 'Curves Map Analyzer - by Savoldi Tommaso';
            app.CenterPanelMain = uipanel(app.CurvesMapAnalyzerbySavoldiTommasoUIFigure);
            app.GridLayout2 = uigridlayout(app.CenterPanelMain);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Compute map';
            app.GridLayout3 = uigridlayout(app.MainTab);
            app.RightPanel = uipanel(app.GridLayout3);
            app.MapstatusLabel = uilabel(app.RightPanel);
            app.MapstatusLabel.Text = 'Map status:';
            app.MapStatusLamp = uilamp(app.RightPanel);
            app.MapStatusLamp.Tooltip = {'Red: no map is loaded.'; 'Orange: a map is currently loaded and it''s ready to be processed.'; 'Green: the map loaded has been processed and it''s ready to be saved or analyzed.'};
            app.MapinfoLabel = uilabel(app.RightPanel);
            app.MapinfoLabel.Text = 'Map info:';
            app.MapInfoLabel = uilabel(app.RightPanel);
            app.MapInfoLabel.Interpreter = 'html';
            app.MapInfoLabel.Text = '- no info -';
            app.TitleEditFieldLabel = uilabel(app.RightPanel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.RightPanel, 'text');
            app.TitleEditField.Tooltip = {'Title assigned to the figure.'};
            app.TitleEditField.Placeholder = 'Insert map title...';
            app.ModelParametersLabel = uilabel(app.RightPanel);
            app.ModelParametersLabel.Text = '- Model parameters -';
            app.LEditFieldLabel = uilabel(app.RightPanel);
            app.LEditFieldLabel.Interpreter = 'latex';
            app.LEditFieldLabel.Text = '$L$';
            app.LEditField = uieditfield(app.RightPanel, 'numeric');
            app.LEditField.Limits = [0 Inf];
            app.LEditField.ValueDisplayFormat = '%.2f';
            app.LEditField.Tooltip = {'Map''s edge length in micrometers.'};
            app.LEditField.Value = 1;
            app.kLabel = uilabel(app.RightPanel);
            app.kLabel.Interpreter = 'latex';
            app.kLabel.Text = '$k$';
            app.kEditField = uieditfield(app.RightPanel, 'numeric');
            app.kEditField.Limits = [0 Inf];
            app.kEditField.ValueDisplayFormat = '%.2f';
            app.kEditField.Tooltip = {'Cantilever''s spring constant in N/m.'};
            app.kEditField.Value = 1;
            app.LLabel = uilabel(app.RightPanel);
            app.LLabel.Interpreter = 'latex';
            app.LLabel.Text = '${\mu}m$';
            app.NmLabel = uilabel(app.RightPanel);
            app.NmLabel.Interpreter = 'latex';
            app.NmLabel.Text = '$N/m$';
            app.RLabel = uilabel(app.RightPanel);
            app.RLabel.Interpreter = 'latex';
            app.RLabel.Text = '$R$';
            app.REditField = uieditfield(app.RightPanel, 'numeric');
            app.REditField.Limits = [0.01 Inf];
            app.REditField.ValueDisplayFormat = '%.2f';
            app.REditField.Tooltip = {'Tip''s radius in nm.'};
            app.REditField.Value = 1;
            app.nmLabel = uilabel(app.RightPanel);
            app.nmLabel.Interpreter = 'latex';
            app.nmLabel.Text = '$nm$';
            app.nuEditFieldLabel = uilabel(app.RightPanel);
            app.nuEditFieldLabel.Interpreter = 'latex';
            app.nuEditFieldLabel.Text = '\nu';
            app.nuEditField = uieditfield(app.RightPanel, 'numeric');
            app.nuEditField.Limits = [-1 1];
            app.nuEditField.ValueDisplayFormat = '%.2f';
            app.nuEditField.Tooltip = {'Material''s Poisson''s ratio (has no unity of measure).'};
            app.nuEditField.Value = 0.5;
            app.nLabel_2 = uilabel(app.RightPanel);
            app.nLabel_2.Interpreter = 'latex';
            app.nLabel_2.Text = '$n$';
            app.nEditField = uieditfield(app.RightPanel, 'numeric');
            app.nEditField.Limits = [0 Inf];
            app.nEditField.RoundFractionalValues = 'on';
            app.nEditField.ValueDisplayFormat = '%.0f';
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Tooltip = {'Moving Average Filter''s size (in number of points).'; 'If it''s 0 the filter is disabled.'};
            app.nEditField.Value = 1;
            app.nLabel = uilabel(app.RightPanel);
            app.nLabel.Interpreter = 'latex';
            app.nLabel.Text = '$pt$';
            app.R2minLabel = uilabel(app.RightPanel);
            app.R2minLabel.Interpreter = 'latex';
            app.R2minLabel.Text = '$R^2_{min}$';
            app.R2_minEditField = uieditfield(app.RightPanel, 'numeric');
            app.R2_minEditField.Limits = [0 1];
            app.R2_minEditField.ValueDisplayFormat = '%.3f';
            app.R2_minEditField.Tooltip = {'Every point fit must have a Rsq value higher than this.'; 'Rsq gives an idea of how good the fit is.'; 'Rsq = 1 means the fit represents 100% of the data.'; ''; 'By putting Rsq min = 0 no point is excluded, even the ones with terrible (Rsq ~ 0) fit.'};
            app.ProcessMapButton = uibutton(app.RightPanel, 'push');
            app.ProcessMapButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessMapButtonPushed, true);
            app.ProcessMapButton.Enable = 'off';
            app.ProcessMapButton.Tooltip = {'Start processing the curve map and show the elastic modulus'' map.'};
            app.ProcessMapButton.Text = 'Process map';
            app.SaveDataButton = uibutton(app.RightPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Icon = fullfile(pathToMLAPP, 'icons', 'save-icon.png');
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Tooltip = {'Save processed data as matrix in a custom file.'};
            app.SaveDataButton.Text = '';
            app.CenterPanelChild = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.CenterPanelChild);
            app.MainGraph = uiaxes(app.GridLayout4);
            title(app.MainGraph, 'Force curve')
            xlabel(app.MainGraph, 'z [nm]')
            ylabel(app.MainGraph, 'd [nm]')
            zlabel(app.MainGraph, 'Z')
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.BackgroundlimitSlider = uislider(app.GridLayout5);
            app.BackgroundlimitSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.BackgroundlimitSlider.ValueChangedFcn = createCallbackFcn(app, @BackgroundlimitSliderValueChanged, true);
            app.BackgroundlimitSlider.ValueChangingFcn = createCallbackFcn(app, @BackgroundlimitSliderValueChanging, true);
            app.BackgroundlimitSlider.Tooltip = {'Move the background fit''s limit for all curves of the map. '; 'If the background limit is set to 100%, no fit is made and only the offset will be adjusted.'};
            app.BackgroundlimitSlider.Value = 80;
            app.BackgroundlimitLabel = uilabel(app.GridLayout5);
            app.BackgroundlimitLabel.Text = 'Background limit [%]';
            app.LeftPanel = uipanel(app.GridLayout3);
            app.CalibrationLabel = uilabel(app.LeftPanel);
            app.CalibrationLabel.Text = '- Calibration -';
            app.SlopeEditFieldLabel = uilabel(app.LeftPanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SlopeEditField.Limits = [1 Inf];
            app.SlopeEditField.ValueDisplayFormat = '%.2f';
            app.SlopeEditField.ValueChangedFcn = createCallbackFcn(app, @SlopeEditFieldValueChanged, true);
            app.SlopeEditField.Value = 1;
            app.aunmLabel = uilabel(app.LeftPanel);
            app.aunmLabel.Text = 'au/nm';
            app.CalculateSlopeFromFileButton = uibutton(app.LeftPanel, 'push');
            app.CalculateSlopeFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSlopeFromFileButtonPushed, true);
            app.CalculateSlopeFromFileButton.Text = 'Calculate from file';
            app.SlopeDescription = uilabel(app.LeftPanel);
            app.SlopeDescription.Text = 'Note: background is fitted on the last 20% of the curve. Slope data is retrieved from z = 0 to -∞.';
            app.ForcecurveLabel = uilabel(app.LeftPanel);
            app.ForcecurveLabel.Text = '- Force curve -';
            app.LoadCurve = uibutton(app.LeftPanel, 'push');
            app.LoadCurve.ButtonPushedFcn = createCallbackFcn(app, @LoadCurveButtonPushed, true);
            app.LoadCurve.Text = 'Load single curve';
            app.CurvemapLabel = uilabel(app.LeftPanel);
            app.CurvemapLabel.Text = '- Curve map -';
            app.LoadMap = uibutton(app.LeftPanel, 'push');
            app.LoadMap.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMap.Text = 'Load curve map';
            app.GraphoptionsLabel = uilabel(app.LeftPanel);
            app.GraphoptionsLabel.Text = '- Graph options -';
            app.SmoothedunloadcurveCheckBox = uicheckbox(app.LeftPanel);
            app.SmoothedunloadcurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothedunloadcurveCheckBoxValueChanged, true);
            app.SmoothedunloadcurveCheckBox.Tooltip = {'Show the smoothed unload curve that will be used during the elastic modulus computation. Change n value to change the smoothing factor.'};
            app.SmoothedunloadcurveCheckBox.Text = 'Smoothed unload curve';
            app.RemoveBackground = uibutton(app.LeftPanel, 'push');
            app.RemoveBackground.ButtonPushedFcn = createCallbackFcn(app, @RemoveBackgroundButtonPushed, true);
            app.RemoveBackground.Text = 'Remove background';
            app.ScriptsLabel = uilabel(app.LeftPanel);
            app.ScriptsLabel.Text = '- Scripts -';
            app.SelectscriptsfolderButton = uibutton(app.LeftPanel, 'push');
            app.SelectscriptsfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectscriptsfolderButtonPushed, true);
            app.SelectscriptsfolderButton.Tooltip = {'Select the folder inside which are saved the scripts.'};
            app.SelectscriptsfolderButton.Text = 'Select scripts folder';
            app.ScriptsDropDown = uidropdown(app.LeftPanel);
            app.ScriptsDropDown.Items = {};
            app.ScriptsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ScriptsDropDownOpening, true);
            app.ScriptsDropDown.Enable = 'off';
            app.ScriptsDropDown.Tooltip = {'Select a folder first. Use this drop down selector to select which script to execute.'};
            app.ScriptsDropDown.Placeholder = 'Empty';
            app.ScriptsDropDown.Value = {};
            app.StartScriptButton = uibutton(app.LeftPanel, 'push');
            app.StartScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StartScriptButtonPushed, true);
            app.StartScriptButton.Enable = 'off';
            app.StartScriptButton.Tooltip = {'Execute the script selected by the drop down selector.'};
            app.StartScriptButton.Text = 'Start';
            app.AnalyzedataTab = uitab(app.TabGroup);
            app.AnalyzedataTab.Title = 'Analyze data';
            app.GridLayout6 = uigridlayout(app.AnalyzedataTab);
            app.EmapAxes = uiaxes(app.GridLayout6);
            title(app.EmapAxes, 'Elastic Modulus Map')
            xlabel(app.EmapAxes, 'X')
            ylabel(app.EmapAxes, 'Y')
            zlabel(app.EmapAxes, 'Z')
            app.HistAxes = uiaxes(app.GridLayout6);
            title(app.HistAxes, 'Histogram')
            xlabel(app.HistAxes, 'X')
            ylabel(app.HistAxes, 'Y')
            zlabel(app.HistAxes, 'Z')
            app.OptionsPanel = uipanel(app.GridLayout6);
            app.OptionsPanel.Title = '- Options -';
            app.GridLayout7 = uigridlayout(app.OptionsPanel);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.SelectMapRadioButton = uibuttongroup(app.GridLayout8);
            app.SelectMapRadioButton.SelectionChangedFcn = createCallbackFcn(app, @SelectMapRadioButtonSelectionChanged, true);
            app.SelectMapRadioButton.Tooltip = {'Select which map to analyze. Use the already computed map or load an exported map.'};
            app.UseComputedMapButton = uiradiobutton(app.SelectMapRadioButton);
            app.UseComputedMapButton.Text = 'Use computed map';
            app.UseComputedMapButton.Value = true;
            app.LoadEMapButton = uiradiobutton(app.SelectMapRadioButton);
            app.LoadEMapButton.Text = 'Load external map';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.TitleEditField_2Label = uilabel(app.GridLayout9);
            app.TitleEditField_2Label.Text = 'Title';
            app.TitleEditFieldSecondTab = uieditfield(app.GridLayout9, 'text');
            app.TitleEditFieldSecondTab.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldSecondTabValueChanged, true);
            app.TitleEditFieldSecondTab.Tooltip = {'Title assigned to the figure.'};
            app.TitleEditFieldSecondTab.Placeholder = 'Insert map title...';
            app.GridLayout10 = uigridlayout(app.GridLayout7);
            app.GridLayout9_2 = uigridlayout(app.GridLayout10);
            app.LEditField_2Label = uilabel(app.GridLayout9_2);
            app.LEditField_2Label.Interpreter = 'latex';
            app.LEditField_2Label.Text = '$L$';
            app.LEditFieldSecondTab = uieditfield(app.GridLayout9_2, 'numeric');
            app.LEditFieldSecondTab.Limits = [0 Inf];
            app.LEditFieldSecondTab.ValueDisplayFormat = '%.2f';
            app.LEditFieldSecondTab.ValueChangedFcn = createCallbackFcn(app, @LEditFieldSecondTabValueChanged, true);
            app.LEditFieldSecondTab.Tooltip = {'Map''s edge length in micrometers.'};
            app.LEditFieldSecondTab.Value = 1;
            app.LLabelSecondTab = uilabel(app.GridLayout9_2);
            app.LLabelSecondTab.Interpreter = 'latex';
            app.LLabelSecondTab.Text = '${\mu}m$';
            app.GridLayout9_3 = uigridlayout(app.GridLayout10);
            app.umDropDownLabel = uilabel(app.GridLayout9_3);
            app.umDropDownLabel.Text = 'u.m.';
            app.umDropDown = uidropdown(app.GridLayout9_3);
            app.umDropDown.Items = {'mPa', 'Pa', 'kPa', 'MPa', 'GPa'};
            app.umDropDown.ValueChangedFcn = createCallbackFcn(app, @umDropDownValueChanged, true);
            app.umDropDown.Tooltip = {'Select which unity of measure to use to show the elastic modulus.'};
            app.umDropDown.Value = 'Pa';
            app.GridLayout11 = uigridlayout(app.GridLayout7);
            app.ScaleLabel = uilabel(app.GridLayout11);
            app.ScaleLabel.Text = 'Scale';
            app.GridLayout13 = uigridlayout(app.GridLayout11);
            app.ScaleDropDown = uidropdown(app.GridLayout13);
            app.ScaleDropDown.Items = {'linear', 'log'};
            app.ScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleDropDownValueChanged, true);
            app.ScaleDropDown.Tooltip = {'Select the type of scale to use.'; 'The logarithmic scale is base 10.'};
            app.ScaleDropDown.Value = 'linear';
            app.GridLayout13_2 = uigridlayout(app.GridLayout11);
            app.ResetButton = uibutton(app.GridLayout13_2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset scale limits to the maximum and minimum values found in the map.'};
            app.ResetButton.Text = 'Reset';
            app.GridLayout14 = uigridlayout(app.GridLayout11);
            app.lowerLimitEditField = uieditfield(app.GridLayout14, 'numeric');
            app.lowerLimitEditField.ValueDisplayFormat = '%.3f';
            app.lowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @lowerLimitEditFieldValueChanged, true);
            app.lowerLimitEditField.Tooltip = {'Lower limit of the scale.'};
            app.GridLayout14_2 = uigridlayout(app.GridLayout11);
            app.upperLimitEditField = uieditfield(app.GridLayout14_2, 'numeric');
            app.upperLimitEditField.ValueDisplayFormat = '%.3f';
            app.upperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @upperLimitEditFieldValueChanged, true);
            app.upperLimitEditField.Tooltip = {'Upper limit of the scale.'};
            app.GridLayout11_2 = uigridlayout(app.GridLayout7);
            app.HistogramLabel = uilabel(app.GridLayout11_2);
            app.HistogramLabel.Text = 'Histogram';
            app.GridLayout15 = uigridlayout(app.GridLayout11_2);
            app.BinsizeEditFieldLabel = uilabel(app.GridLayout15);
            app.BinsizeEditFieldLabel.Text = 'Bin size';
            app.BinSizeEditField = uieditfield(app.GridLayout15, 'numeric');
            app.BinSizeEditField.Limits = [0 Inf];
            app.BinSizeEditField.ValueDisplayFormat = '%.3f';
            app.BinSizeEditField.ValueChangedFcn = createCallbackFcn(app, @BinSizeEditFieldValueChanged, true);
            app.BinSizeEditField.Tooltip = {'Define size of the bins. If it''s zero, the program decides by it''s own.'};
            app.GridLayout13_3 = uigridlayout(app.GridLayout11_2);
            app.NormalizationDropDown = uidropdown(app.GridLayout13_3);
            app.NormalizationDropDown.Items = {'count', 'pdf'};
            app.NormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizationDropDownValueChanged, true);
            app.NormalizationDropDown.Tooltip = {'Select the type of normaliztion to use.'};
            app.NormalizationDropDown.Value = 'count';
            app.GridLayout16 = uigridlayout(app.GridLayout11_2);
            app.SaveHistogramButton = uibutton(app.GridLayout16, 'push');
            app.SaveHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @SaveHistogramButtonPushed, true);
            app.SaveHistogramButton.Icon = fullfile(pathToMLAPP, 'icons', 'save-icon.png');
            app.SaveHistogramButton.Enable = 'off';
            app.SaveHistogramButton.Tooltip = {'Save histogram as matrix in a custom file.'};
            app.SaveHistogramButton.Text = '';
            app.GeneratefigurePanel = uipanel(app.GridLayout6);
            app.GeneratefigurePanel.Title = '- Generate figure -';
            app.GridLayout17 = uigridlayout(app.GeneratefigurePanel);
            app.GridLayout17_2 = uigridlayout(app.GridLayout17);
            app.ElasticmodulusmapCheckBox = uicheckbox(app.GridLayout17_2);
            app.ElasticmodulusmapCheckBox.Text = 'Elastic modulus map';
            app.HeightmapCheckBox = uicheckbox(app.GridLayout17_2);
            app.HeightmapCheckBox.Text = 'Height map';
            app.HistogramCheckBox = uicheckbox(app.GridLayout17_2);
            app.HistogramCheckBox.Text = 'Histogram';
            app.GridLayout19 = uigridlayout(app.GridLayout17);
            app.GridLayout18 = uigridlayout(app.GridLayout19);
            app.GeneratefigureButton = uibutton(app.GridLayout18, 'push');
            app.GeneratefigureButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefigureButtonPushed, true);
            app.GeneratefigureButton.Text = 'Generate figure';
            app.GridLayout20 = uigridlayout(app.GridLayout19);
            app.umDropDown_2Label = uilabel(app.GridLayout20);
            app.umDropDown_2Label.Text = 'u.m.';
            app.umDropDown_2 = uidropdown(app.GridLayout20);
            app.umDropDown_2.Items = {'nm', 'um'};
            app.umDropDown_2.Tooltip = {'Select which unity of measure to use to show the elastic modulus.'};
            app.umDropDown_2.Value = 'nm';
            app.LoadHMapButton = uibutton(app.GridLayout20, 'push');
            app.LoadHMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHMapButtonPushed, true);
            app.LoadHMapButton.Tooltip = {'Load height map before generating the figure.'};
            app.LoadHMapButton.Text = 'Load height map';
            app.Label = uilabel(app.GridLayout20);
            app.Label.Text = '';
            app.HMapLamp = uilamp(app.GridLayout20);
            app.HMapLamp.Tooltip = {'Height map not loaded.'};
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.GridLayout21 = uigridlayout(app.PCATab);
            app.GridLayout22 = uigridlayout(app.GridLayout21);
            app.SpectrumAxes = uiaxes(app.GridLayout22);
            title(app.SpectrumAxes, 'Raman Peaks')
            xlabel(app.SpectrumAxes, 'raman shift [cm^{-1}]')
            ylabel(app.SpectrumAxes, 'intensity [au]')
            zlabel(app.SpectrumAxes, 'Z')
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.PCAxes = uiaxes(app.GridLayout23);
            title(app.PCAxes, 'PC Map')
            xlabel(app.PCAxes, 'x [um]')
            ylabel(app.PCAxes, 'y [um]')
            zlabel(app.PCAxes, 'Z')
            app.HeightMapAxes = uiaxes(app.GridLayout23);
            title(app.HeightMapAxes, 'Heigth Map')
            xlabel(app.HeightMapAxes, 'X')
            ylabel(app.HeightMapAxes, 'Y')
            zlabel(app.HeightMapAxes, 'Z')
            app.GridLayout24 = uigridlayout(app.GridLayout21);
            app.GridLayout25 = uigridlayout(app.GridLayout24);
            app.ExtractpeakstableButton = uibutton(app.GridLayout25, 'push');
            app.ExtractpeakstableButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractpeakstableButtonPushed, true);
            app.ExtractpeakstableButton.Tooltip = {'Exctract peaks table from peaks fitted using LabSpec.'};
            app.ExtractpeakstableButton.Text = 'Extract peaks table';
            app.GridLayout26 = uigridlayout(app.GridLayout25);
            app.SavePeaksTableButton = uibutton(app.GridLayout26, 'push');
            app.SavePeaksTableButton.ButtonPushedFcn = createCallbackFcn(app, @SavePeaksTableButtonPushed, true);
            app.SavePeaksTableButton.Icon = fullfile(pathToMLAPP, 'icons', 'save-icon.png');
            app.SavePeaksTableButton.Enable = 'off';
            app.SavePeaksTableButton.Tooltip = {'Save peaks table as matrix in a custom file.'};
            app.SavePeaksTableButton.Text = '';
            app.GridLayout25_2 = uigridlayout(app.GridLayout24);
            app.PeaksTableLamp = uilamp(app.GridLayout25_2);
            app.PeaksTableLamp.Tooltip = {'Red: no peaks table is loaded.'; 'Orange: a peaks table is loaded but no PCA was performed.'; 'Green: a PCA was loaded or was performed.'};
            app.LoadPeaksTableButton = uibutton(app.GridLayout25_2, 'push');
            app.LoadPeaksTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPeaksTableButtonPushed, true);
            app.LoadPeaksTableButton.Tooltip = {'Exctract peaks table from peaks fitted using LabSpec.'};
            app.LoadPeaksTableButton.Text = 'Load peaks table';
            app.GridLayout27 = uigridlayout(app.GridLayout24);
            app.ExecutePCAButton = uibutton(app.GridLayout27, 'push');
            app.ExecutePCAButton.Enable = 'off';
            app.ExecutePCAButton.Tooltip = {'Excecute PCA on the currently loaded peaks table.'};
            app.ExecutePCAButton.Text = 'Execute PCA';
            app.LoadPCAButton = uibutton(app.GridLayout27, 'push');
            app.LoadPCAButton.Tooltip = {'Load the results from a PCA done previously.'; 'Select the directory which contains the following files:'; '- ''coeff.txt'''; '- ''score.txt'''};
            app.LoadPCAButton.Text = 'Load PCA';
            % Show the figure after all components are created
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = curves_map_analyzer_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CurvesMapAnalyzerbySavoldiTommasoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CurvesMapAnalyzerbySavoldiTommasoUIFigure)
        end
    end
end