classdef curves_map_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CurvesMapAnalyzerbySavoldiTommasoUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SelectscriptsfolderButton     matlab.ui.control.Button
        StartScriptButton             matlab.ui.control.Button
        ScriptsDropDown               matlab.ui.control.DropDown
        ScriptsLabel                  matlab.ui.control.Label
        RemoveBackground              matlab.ui.control.Button
        SmoothedunloadcurveCheckBox   matlab.ui.control.CheckBox
        GraphoptionsLabel             matlab.ui.control.Label
        CurvemapLabel                 matlab.ui.control.Label
        LoadMap                       matlab.ui.control.Button
        SlopeDescription              matlab.ui.control.Label
        ForcecurveLabel               matlab.ui.control.Label
        LoadCurve                     matlab.ui.control.Button
        CalibrationLabel              matlab.ui.control.Label
        aunmLabel                     matlab.ui.control.Label
        CalculateSlopeFromFileButton  matlab.ui.control.Button
        SlopeEditField                matlab.ui.control.NumericEditField
        SlopeEditFieldLabel           matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        BackgroundlimitSlider         matlab.ui.control.Slider
        BackgroundlimitLabel          matlab.ui.control.Label
        MainGraph                     matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        SaveDataButton                matlab.ui.control.Button
        GenerateHistogramButton       matlab.ui.control.Button
        PaLabel                       matlab.ui.control.Label
        ToEditField                   matlab.ui.control.NumericEditField
        toLabel                       matlab.ui.control.Label
        FromLabel                     matlab.ui.control.Label
        FromEditField                 matlab.ui.control.NumericEditField
        BinsEditField                 matlab.ui.control.NumericEditField
        BinsEditFieldLabel            matlab.ui.control.Label
        HistogramOptionsLabel         matlab.ui.control.Label
        TitleEditField                matlab.ui.control.EditField
        TitleEditFieldLabel           matlab.ui.control.Label
        LEditField                    matlab.ui.control.NumericEditField
        LEditFieldLabel               matlab.ui.control.Label
        LLabel                        matlab.ui.control.Label
        ProcessMapButton              matlab.ui.control.Button
        R2_minEditField               matlab.ui.control.NumericEditField
        R2minLabel                    matlab.ui.control.Label
        R2_minLabel                   matlab.ui.control.Label
        nEditField                    matlab.ui.control.NumericEditField
        nLabel_2                      matlab.ui.control.Label
        nLabel                        matlab.ui.control.Label
        nuEditField                   matlab.ui.control.NumericEditField
        nuEditFieldLabel              matlab.ui.control.Label
        nuLabel                       matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        RLabel                        matlab.ui.control.Label
        nmLabel                       matlab.ui.control.Label
        ModelParametersLabel          matlab.ui.control.Label
        kEditField                    matlab.ui.control.NumericEditField
        kLabel                        matlab.ui.control.Label
        NmLabel                       matlab.ui.control.Label
        MapInfoLabel                  matlab.ui.control.Label
        MapinfoLabel                  matlab.ui.control.Label
        MapStatusLamp                 matlab.ui.control.Lamp
        MapstatusLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        % Variables starting with h_ are handles to the plot
        
        map_filepath = 0;       % Current map loaded filepath
        map_title = 0;          % Current map loaded title
        map_size = 0;           % Current map loaded size in points
        map_Emap = 0;           % Current map loaded Emap processed
        h_bl_line = 0;          % Background limit line
        h_smoothed_curve = 0;   % Smoothed curve
        shown_curve_l = 0;      % The load curve currently being shown
        shown_curve_u = 0;      % The unload curve currently being shown
        h_shown_curve_l = 0;    % The load curve currently being shown
        h_shown_curve_u = 0;    % The unload curve currently being shown
        
        plotted_curves = [];    % List that keeps track of the curves being plotted

        scripts_folder = 0;     % Path to the folder inside which are stored the scripts to be executed
        scripts_list = [];      % List containing all the available scripts

        % Costants
        NO_MAP_COLOR        = [1, 0, 0];
        LOADED_MAP_COLOR    = [0.92, 0.66, 0.20];
        PROCESSED_MAP_COLOR = [0, 1, 0];
    end
    
    properties (Access = public)
        Emap = 0;               % Current Emap loaded
    end
    
    methods (Access = private)
        
        function [filepath, name, dir]  = pickFile(app)
            [name, dir] = uigetfile('*.*');
            
            if isequal(name, 0)
                alert(app, 'Invalid File', 'File not found.', 'error');
                filepath = 0;
            else
                filepath = fullfile(dir, name);
            end
        end

        function path  = pickDir(app)
            path = uigetdir();
            
            if isequal(path, 0)
                alert(app, 'Invalid Directory', 'Directory not found.', 'error');
            end
        end
        
        function alert(app, title, text, icon)
            fig = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure;
            uialert(fig, text, title, 'Icon', icon);
        end
        
        function id = plot_curve(app, x, y, displayName, color, style)
            if isequal(color, 0)
                h = plot(app.MainGraph, x, y, 'DisplayName', displayName, 'LineStyle', style);
            else
                h = plot(app.MainGraph, x, y, 'DisplayName', displayName, 'Color', color, 'LineStyle', style);
            end
            
            % Create curve obj
            % It consists of
            % h = handle
            % x, y, displayName, color, style = plot options
            curve = {h, x, y, displayName, color, style};
            
            id = length(app.plotted_curves);
            app.plotted_curves = [app.plotted_curves, curve];
        end
        
        function clear_plot(app)
            clf(app.MainGraph);
            app.plotted_curves = [];
        end
        
        function erase_curve(app, id)
            curve = app.plotted_curves(id);
            delete(curve{1});
            app.plotted_curves(id) = 0;
        end
        
        function modify_curve_data(app, id, x, y)
            curve = app.plotted_curves(id);
            delete(curve{1});

            if isequal(curve{5}, 0)
                h = plot(app.MainGraph, x, y, 'DisplayName', curve{4}, 'LineStyle', curve{6});
            else
                h = plot(app.MainGraph, x, y, 'DisplayName', curve{4}, 'Color', curve{5}, 'LineStyle', curve{6});
            end
            
            curve{1} = h;
            curve{2} = x;
            curve{3} = y;

            app.plotted_curves(id) = curve;
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath ./functions/
            xlim(app.MainGraph, [0 100]);
            hold(app.MainGraph, 'on');
            grid(app.MainGraph, 'on');
            legend(app.MainGraph, 'show', 'Location', 'best');
        end

        % Button pushed function: CalculateSlopeFromFileButton
        function CalculateSlopeFromFileButtonPushed(app, event)
            filepath = pickFile(app);
            if ~isequal(filepath, 0)
                % Now that the file has been selected, calculate the slope
                % Load the calibration unload curve
                try
                    [~, ~, zu, Nfu] = load_force_curve(filepath);
                catch
                    % File can not be read. Alert the user
                    alert(app, 'Valid file', ['File ' filepath ' can not be read. Try removing the first line (it should contain non numeric values).'], 'error');
                    return
                end
                % Calculate slope starting from 80 % of the max zu to the
                % end and from the top of the curve to the middle point
                slope = calculate_slope(zu, Nfu, 0.80 * max(zu), max(zu));

                % Modify the slopeEditField rounding to the second decimal
                % value
                app.SlopeEditField.Value = round(slope, 2);
                
                % Notify the user
                alert(app, 'Valid file', ['User selected ' filepath ' which gave a slope of ' num2str(slope) ' au/nm.'], 'success');
            end
        end

        % Button pushed function: LoadCurve
        function LoadCurveButtonPushed(app, event)
            filepath = pickFile(app);
            if ~isequal(filepath, 0)
                % Now that the file has been selected, load the curve
                try
                    [zl, Nfl, zu, Nfu] = load_force_curve(filepath);
                catch
                    % File can not be read. Alert the user
                    alert(app, 'Valid file', ['File ' filepath ' can not be read. Try removing the first line (it should contain non numeric values).'], 'error');
                    return
                end
                % Activate hold on
                hold(app.MainGraph, 'on');
                % Plot the curve on the mainGraph
                % Calculate the deflection by dividing by slope value
                slope = app.SlopeEditField.Value;
                app.h_shown_curve_l = plot(app.MainGraph, zl, Nfl / slope, 'DisplayName', 'load curve', 'Color', 'r');
                app.h_shown_curve_u = plot(app.MainGraph, zu, Nfu / slope, 'DisplayName', 'unload curve', 'Color', 'b');
                % Show legend
                legend(app.MainGraph, 'show');
                % Notify the user
                alert(app, 'Valid file', ['Curve contained in file ' filepath ' has been successfully loaded.'], 'success');

                app.shown_curve_l = [zl, Nfl];
                app.shown_curve_u = [zu, Nfu];
            end
        end

        % Button pushed function: ProcessMapButton
        function ProcessMapButtonPushed(app, event)
            if isequal(app.map_filepath, 0)
                % No map is loaded
                alert(app, 'Fitting process error', 'No map is loaded.', 'error');
                return
            end
            
            slope = app.SlopeEditField.Value;
            k = app.kEditField.Value;
            R = app.REditField.Value * 1e-9;
            v = app.nuEditField.Value;
            n = app.nEditField.Value;
            Rsq_min = app.R2_minEditField.Value;
            L = app.LEditField.Value;
            b_start = app.BackgroundlimitSlider.Value / 100; 

            try                
                [app.Emap, ~] = calculate_E_map(app.map_filepath, slope, k, R, v, n, Rsq_min, b_start);
            catch
                % File can not be read. Alert the user
                alert(app, 'File not valid', ['File ' app.map_filepath ' can not be read. Try removing the first line (it should contain only four numeric values).'], 'error');
                return
            end
            
            % Show the computed map
            show_E_map(app.Emap, L, 'Pa', app.TitleEditField.Value);
            app.map_Emap = app.Emap;
            % Update the lamp
            app.MapStatusLamp.Color = app.PROCESSED_MAP_COLOR;
            % Enable save data button
            app.SaveDataButton.Enable = "on";
            app.GenerateHistogramButton.Enable = "on";
        end

        % Button pushed function: LoadMap
        function LoadMapButtonPushed(app, event)
            [filepath, name] = pickFile(app);
            if ~isequal(filepath, 0)
                % Now that the file has been selected, test if it's a valid
                % map file
                try
                    data = load(filepath);
                    % If no exception is thrown it is a valid file
                    % Calculate map size
                    s = size(data);
                    r = s(1);
                    app.map_size = (r - 1);
                    % Remove everything after the dot, so it removes '.txt'
                    app.map_title = name(1:strfind(name,'.')-1);
                    
                    % Update the lamp and text
                    app.MapStatusLamp.Color = app.LOADED_MAP_COLOR;
                    app.MapInfoLabel.Text = sprintf('Name: %s\nSize: %d pt', app.map_title, app.map_size);
                    if isempty(app.TitleEditField.Value)
                        app.TitleEditField.Value = app.map_title;
                    end
                    app.map_filepath = filepath;
                    drawnow;

                    % Enable process button
                    app.ProcessMapButton.Enable = "on";
                    % Disable save data and generate histogram buttons
                    app.SaveDataButton.Enable = "off";
                    app.GenerateHistogramButton.Enable = "off";

                    % Plot on the main graph some curves
                    cla(app.MainGraph);
                    hold(app.MainGraph, 'on');
                    grid(app.MainGraph, 'on');
                    legend(app.MainGraph, 'show', 'Location', 'best');
                    zul = data(1,:)';
                    Nful_list = data(2:r, :)';
                    % zl = zul(1:end/2);
                    z = zul((end/2+1):end);
                    % Nfl_list = Nful_list(1:end/2, :);
                    Nfu_list = Nful_list((end/2+1):end, :);
                    d_list = Nfu_list / app.SlopeEditField.Value;
                    mean_curve = mean(d_list, 2);
                    mode_curve = mode(d_list, 2);
                    median_curve = median(d_list, 2);
                    plot(app.MainGraph, z, mean_curve, 'DisplayName', 'mean curve');
                    plot(app.MainGraph, z, mode_curve, 'DisplayName', 'mode curve');
                    plot(app.MainGraph, z, median_curve, 'DisplayName', 'median curve');
                    clear data;
                catch
                    % Not a valid map file                    
                    app.map_filepath = 0;
                    % Update the lamp and text
                    app.MapStatusLamp.Color = app.NO_MAP_COLOR;
                    app.MapInfoLabel.Text = sprintf('- no info -');
                    drawnow;
                    % Disable process button
                    app.ProcessMapButton.Enable = "off";
                    % Disable save data button
                    app.SaveDataButton.Enable = "off";
                    app.GenerateHistogramButton.Enable = "off";
                    % Alert the user
                    alert(app, 'File not valid', ['File ' filepath ' can not be read. Try removing the first line (it should contain only four numeric values).'], 'error');
                end
            end
        end

        % Value changed function: BackgroundlimitSlider
        function BackgroundlimitSliderValueChanged(app, event)
            value = app.BackgroundlimitSlider.Value;
            
            if ~isequal(app.h_bl_line, 0)
                % Remove the old line
                delete(app.h_bl_line);
            end
            app.h_bl_line = xline(app.MainGraph, value, 'DisplayName', 'Background limit', 'LineStyle','--');
        end

        % Value changing function: BackgroundlimitSlider
        function BackgroundlimitSliderValueChanging(app, event)
            changingValue = event.Value;
            if ~isequal(app.h_bl_line, 0)
                % Remove the old line
                delete(app.h_bl_line);
            end
            app.h_bl_line = xline(app.MainGraph, changingValue, 'DisplayName', 'Background limit', 'LineStyle','--');
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            
            % Update the curve if it is being shown
            if app.SmoothedunloadcurveCheckBox.Value
                % Calculate the smooth curve
                [smooth_z, smooth_Nf] = moving_average_filter(app.shown_curve_u(:, 1), app.shown_curve_u(:, 2), value);
                
                % Remove the old one (only if it exists) and replace it with the new one
                if ~isequal(app.h_smoothed_curve, 0)
                    delete(app.h_smoothed_curve);
                end
                
                app.h_smoothed_curve = plot(app.MainGraph, smooth_z, smooth_Nf / app.SlopeEditField.Value, 'DisplayName', 'smoothed curve', 'Color', 'cyan', 'LineStyle', '-.');
            end
        end

        % Value changed function: SmoothedunloadcurveCheckBox
        function SmoothedunloadcurveCheckBoxValueChanged(app, event)
            value = app.SmoothedunloadcurveCheckBox.Value;
            
            if value && ~isequal(app.shown_curve_u, 0)
                % Show the curve
                
                % Calculate the smooth curve
                [smooth_z, smooth_Nf] = moving_average_filter(app.shown_curve_u(:, 1), app.shown_curve_u(:, 2), app.nEditField.Value);

                app.h_smoothed_curve = plot(app.MainGraph, smooth_z, smooth_Nf / app.SlopeEditField.Value, 'DisplayName', 'smoothed curve', 'Color', 'cyan', 'LineStyle', '-.');
            else
                if ~isequal(app.h_smoothed_curve, 0)
                    % Only if it exists
                    delete(app.h_smoothed_curve);
                end
            end
        end

        % Value changed function: SlopeEditField
        function SlopeEditFieldValueChanged(app, event)
            value = app.SlopeEditField.Value;
            
            % Update shown curve
            if ~isequal(app.shown_curve_l, 0)
                if ~isequal(app.h_shown_curve_l, 0)
                    delete(app.h_shown_curve_l);
                end
                app.h_shown_curve_l = plot(app.MainGraph, app.shown_curve_l(:, 1), app.shown_curve_l(:, 2) / value, 'DisplayName', 'load curve', 'Color', 'r');
            end
            
            if ~isequal(app.shown_curve_u, 0)
                if ~isequal(app.h_shown_curve_u, 0)
                    delete(app.h_shown_curve_u);
                end
                app.h_shown_curve_u = plot(app.MainGraph, app.shown_curve_u(:, 1), app.shown_curve_u(:, 2) / value, 'DisplayName', 'unload curve', 'Color', 'b');
            end
            
            if app.SmoothedunloadcurveCheckBox.Value && ~isequal(app.h_smoothed_curve, 0)
                delete(app.h_smoothed_curve);
                
                [smooth_z, smooth_Nf] = moving_average_filter(app.shown_curve_u(:, 1), app.shown_curve_u(:, 2), app.nEditField.Value);
                app.h_smoothed_curve = plot(app.MainGraph, smooth_z, smooth_Nf / app.SlopeEditField.Value, 'DisplayName', 'smoothed curve', 'Color', 'cyan', 'LineStyle', '-.');
            end
        end

        % Button pushed function: RemoveBackground
        function RemoveBackgroundButtonPushed(app, event)
            b_start = app.BackgroundlimitSlider.Value / 100;
            slope = app.SlopeEditField.Value;
            if ~isequal(app.shown_curve_l, 0)
                if ~isequal(app.h_shown_curve_l, 0)
                    delete(app.h_shown_curve_l);
                end
                z = app.shown_curve_l(:, 1);
                Nf = app.shown_curve_l(:, 2);
                Nf = remove_background(z, Nf, max(z) * b_start, Inf);
                app.shown_curve_l = [z, Nf];

                app.h_shown_curve_l = plot(app.MainGraph, z, Nf/slope, 'DisplayName', 'load curve', 'Color', 'r');
            end
            if ~isequal(app.shown_curve_u, 0)
                if ~isequal(app.h_shown_curve_u, 0)
                    delete(app.h_shown_curve_u);
                end
                z = app.shown_curve_u(:, 1);
                Nf = app.shown_curve_u(:, 2);
                Nf = remove_background(z, Nf, max(z) * b_start, Inf);
                app.shown_curve_u = [z, Nf];

                app.h_shown_curve_u = plot(app.MainGraph, z, Nf/slope, 'DisplayName', 'unload curve', 'Color', 'b');
            end
            if app.SmoothedunloadcurveCheckBox.Value && ~isequal(app.shown_curve_u, 0)
                if ~isequal(app.h_smoothed_curve, 0)
                    delete(app.h_smoothed_curve);
                end
                z = app.shown_curve_u(:, 1);
                Nf = app.shown_curve_u(:, 2);
                [z, Nf] = moving_average_filter(z, Nf, app.nEditField.Value);
                Nf = remove_background(z, Nf, max(z) * b_start, Inf);
   
                app.h_smoothed_curve = plot(app.MainGraph, z, Nf/slope, 'DisplayName', 'smoothed curve', 'Color', 'cyan', 'LineStyle', '-.');
            end
        end

        % Button pushed function: GenerateHistogramButton
        function GenerateHistogramButtonPushed(app, event)
            fig = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure;
            if isequal(app.map_Emap, 0)
                uialert(fig, 'You must process the map first.', 'No map has been processed', 'Icon', 'error');
                return
            end
            
            f = figure;
            axes = gca(f);
            hold(axes, 'on');
            grid(axes, 'on');
            histogram(axes, app.map_Emap, app.BinsEditField.Value, 'Normalization', 'count');
            title(axes, app.TitleEditField.Value);
            xlabel(axes, 'E [Pa]');
            ylabel(axes, 'count');
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            if isequal(app.map_Emap, 0)
                alert(app, 'No map has been processed', 'You must process the map first.', 'error');
                return
            end
            
            try
                [fileName, pathName] = uiputfile({'*.csv'; '*.tsv'; '*.txt'; '*.bin'}, 'Please select a file location', [app.TitleEditField.Value '.csv']);
                writematrix(app.map_Emap, fullfile(pathName, fileName));
            catch
                alert(app, 'Error', 'An error occured while saving the file. Data has not been saved.', 'error');
                return
            end

            alert(app, 'File saved', ['Data has been saved successfully as ' fileName '.'], 'success');
        end

        % Button pushed function: SelectscriptsfolderButton
        function SelectscriptsfolderButtonPushed(app, event)
            path = pickDir(app);

            if isequal(path, 0)
                % Do not change the current path nor the selector list
                return
            end

            app.scripts_folder = path;

            % Find all available scripts
            files = dir(app.scripts_folder);
            app.scripts_list = {};

            for i = 1:length(files)
                name = files(i).name;
                % Check if the extension is '.m'
                if length(name) > 2 && strcmp(name(end-1:end), '.m')
                    app.scripts_list{end+1} = name(1:end-2);
                end
            end

            % Update the selector
            app.ScriptsDropDown.Items = app.scripts_list;
            app.ScriptsDropDown.Enable = 'on';

            % Enable start button only if there are scripts available
            app.StartScriptButton.Enable = ~isempty(app.scripts_list);
            
        end

        % Drop down opening function: ScriptsDropDown
        function ScriptsDropDownOpening(app, event)
            % Update the selector
            % Find all available scripts
            files = dir(app.scripts_folder);
            app.scripts_list = {};
            for i = 1:length(files)
                name = files(i).name;
                % Check if the extension is '.m'
                if length(name) > 2 && strcmp(name(end-1:end), '.m')
                    app.scripts_list{end+1} = name(1:end-2);
                end
            end
            app.ScriptsDropDown.Items = app.scripts_list;
            app.StartScriptButton.Enable = ~isempty(app.scripts_list);
        end

        % Button pushed function: StartScriptButton
        function StartScriptButtonPushed(app, event)
            run(fullfile(app.scripts_folder, [app.ScriptsDropDown.Value '.m']));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {587, 587, 587};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {587, 587};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 219};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure = uifigure('Visible', 'off');
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.Name = 'Curves Map Analyzer - by Savoldi Tommaso';
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CurvesMapAnalyzerbySavoldiTommasoUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SlopeEditFieldLabel = uilabel(app.LeftPanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SlopeEditField.Limits = [1 Inf];
            app.SlopeEditField.ValueDisplayFormat = '%.2f';
            app.SlopeEditField.ValueChangedFcn = createCallbackFcn(app, @SlopeEditFieldValueChanged, true);
            app.SlopeEditField.Value = 1;
            app.CalculateSlopeFromFileButton = uibutton(app.LeftPanel, 'push');
            app.CalculateSlopeFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSlopeFromFileButtonPushed, true);
            app.CalculateSlopeFromFileButton.Text = 'Calculate from file';
            app.aunmLabel = uilabel(app.LeftPanel);
            app.aunmLabel.Text = 'au/nm';
            app.CalibrationLabel = uilabel(app.LeftPanel);
            app.CalibrationLabel.Text = '- Calibration -';
            app.LoadCurve = uibutton(app.LeftPanel, 'push');
            app.LoadCurve.ButtonPushedFcn = createCallbackFcn(app, @LoadCurveButtonPushed, true);
            app.LoadCurve.Text = 'Load single curve';
            app.ForcecurveLabel = uilabel(app.LeftPanel);
            app.ForcecurveLabel.Text = '- Force curve -';
            app.SlopeDescription = uilabel(app.LeftPanel);
            app.SlopeDescription.Text = 'Note: background is fitted on the last 20% of the curve. Slope data is retrieved from z = 0 to -∞.';
            app.LoadMap = uibutton(app.LeftPanel, 'push');
            app.LoadMap.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMap.Text = 'Load curve map';
            app.CurvemapLabel = uilabel(app.LeftPanel);
            app.CurvemapLabel.Text = '- Curve map -';
            app.GraphoptionsLabel = uilabel(app.LeftPanel);
            app.GraphoptionsLabel.Text = '- Graph options -';
            app.SmoothedunloadcurveCheckBox = uicheckbox(app.LeftPanel);
            app.SmoothedunloadcurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothedunloadcurveCheckBoxValueChanged, true);
            app.SmoothedunloadcurveCheckBox.Tooltip = {'Show the smoothed unload curve that will be used during the elastic modulus computation. Change n value to change the smoothing factor.'};
            app.SmoothedunloadcurveCheckBox.Text = 'Smoothed unload curve';
            app.RemoveBackground = uibutton(app.LeftPanel, 'push');
            app.RemoveBackground.ButtonPushedFcn = createCallbackFcn(app, @RemoveBackgroundButtonPushed, true);
            app.RemoveBackground.Text = 'Remove background';
            app.ScriptsLabel = uilabel(app.LeftPanel);
            app.ScriptsLabel.Text = '- Scripts -';
            app.ScriptsDropDown = uidropdown(app.LeftPanel);
            app.ScriptsDropDown.Items = {};
            app.ScriptsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ScriptsDropDownOpening, true);
            app.ScriptsDropDown.Enable = 'off';
            app.ScriptsDropDown.Tooltip = {'Select a folder first. Use this drop down selector to select which script to execute.'};
            app.ScriptsDropDown.Placeholder = 'Empty';
            app.ScriptsDropDown.Value = {};
            app.StartScriptButton = uibutton(app.LeftPanel, 'push');
            app.StartScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StartScriptButtonPushed, true);
            app.StartScriptButton.Enable = 'off';
            app.StartScriptButton.Tooltip = {'Execute the script selected by the drop down selector.'};
            app.StartScriptButton.Text = 'Start';
            app.SelectscriptsfolderButton = uibutton(app.LeftPanel, 'push');
            app.SelectscriptsfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectscriptsfolderButtonPushed, true);
            app.SelectscriptsfolderButton.Tooltip = {'Select the folder inside which are saved the scripts.'};
            app.SelectscriptsfolderButton.Text = 'Select scripts folder';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.MainGraph = uiaxes(app.GridLayout2);
            title(app.MainGraph, 'Force curve')
            xlabel(app.MainGraph, 'z [nm]')
            ylabel(app.MainGraph, 'd [nm]')
            zlabel(app.MainGraph, 'Z')
            app.BackgroundlimitLabel = uilabel(app.GridLayout2);
            app.BackgroundlimitLabel.Text = 'Background limit [%]';
            app.BackgroundlimitSlider = uislider(app.GridLayout2);
            app.BackgroundlimitSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.BackgroundlimitSlider.ValueChangedFcn = createCallbackFcn(app, @BackgroundlimitSliderValueChanged, true);
            app.BackgroundlimitSlider.ValueChangingFcn = createCallbackFcn(app, @BackgroundlimitSliderValueChanging, true);
            app.BackgroundlimitSlider.Tooltip = {'Move the background fit''s limit for all curves of the map. '};
            app.BackgroundlimitSlider.Value = 80;
            app.RightPanel = uipanel(app.GridLayout);
            app.MapstatusLabel = uilabel(app.RightPanel);
            app.MapstatusLabel.Text = 'Map status:';
            app.MapStatusLamp = uilamp(app.RightPanel);
            app.MapStatusLamp.Tooltip = {'Red: no map is loaded.'; 'Orange: a map is currently loaded and it''s ready to be processed.'; 'Green: the map loaded has been processed and it''s ready to be saved or analyzed.'};
            app.MapinfoLabel = uilabel(app.RightPanel);
            app.MapinfoLabel.Text = 'Map info:';
            app.MapInfoLabel = uilabel(app.RightPanel);
            app.MapInfoLabel.Interpreter = 'html';
            app.MapInfoLabel.Text = '- no info -';
            app.NmLabel = uilabel(app.RightPanel);
            app.NmLabel.Interpreter = 'latex';
            app.NmLabel.Text = '$N/m$';
            app.kLabel = uilabel(app.RightPanel);
            app.kLabel.Interpreter = 'latex';
            app.kLabel.Text = '$k$';
            app.kEditField = uieditfield(app.RightPanel, 'numeric');
            app.kEditField.Limits = [0 Inf];
            app.kEditField.ValueDisplayFormat = '%.2f';
            app.kEditField.Tooltip = {'Cantilever''s spring constant in N/m.'};
            app.kEditField.Value = 1;
            app.ModelParametersLabel = uilabel(app.RightPanel);
            app.ModelParametersLabel.Text = '- Model parameters -';
            app.nmLabel = uilabel(app.RightPanel);
            app.nmLabel.Interpreter = 'latex';
            app.nmLabel.Text = '$nm$';
            app.RLabel = uilabel(app.RightPanel);
            app.RLabel.Interpreter = 'latex';
            app.RLabel.Text = '$R$';
            app.REditField = uieditfield(app.RightPanel, 'numeric');
            app.REditField.Limits = [0.01 Inf];
            app.REditField.ValueDisplayFormat = '%.2f';
            app.REditField.Tooltip = {'Tip''s radius in nm.'};
            app.REditField.Value = 1;
            app.nuLabel = uilabel(app.RightPanel);
            app.nuLabel.Text = '';
            app.nuEditFieldLabel = uilabel(app.RightPanel);
            app.nuEditFieldLabel.Interpreter = 'latex';
            app.nuEditFieldLabel.Text = '\nu';
            app.nuEditField = uieditfield(app.RightPanel, 'numeric');
            app.nuEditField.Limits = [-1 1];
            app.nuEditField.ValueDisplayFormat = '%.2f';
            app.nuEditField.Tooltip = {'Material''s Poisson''s ratio (has no unity of measure).'};
            app.nuEditField.Value = 0.5;
            app.nLabel = uilabel(app.RightPanel);
            app.nLabel.Interpreter = 'latex';
            app.nLabel.Text = '$pt$';
            app.nLabel_2 = uilabel(app.RightPanel);
            app.nLabel_2.Interpreter = 'latex';
            app.nLabel_2.Text = '$n$';
            app.nEditField = uieditfield(app.RightPanel, 'numeric');
            app.nEditField.Limits = [0 Inf];
            app.nEditField.RoundFractionalValues = 'on';
            app.nEditField.ValueDisplayFormat = '%.0f';
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Tooltip = {'Moving Average Filter''s size (in number of points).'; 'If it''s 0 the filter is disabled.'};
            app.nEditField.Value = 1;
            app.R2_minLabel = uilabel(app.RightPanel);
            app.R2_minLabel.Text = '';
            app.R2minLabel = uilabel(app.RightPanel);
            app.R2minLabel.Interpreter = 'latex';
            app.R2minLabel.Text = '$R^2_{min}$';
            app.R2_minEditField = uieditfield(app.RightPanel, 'numeric');
            app.R2_minEditField.Limits = [0 1];
            app.R2_minEditField.ValueDisplayFormat = '%.3f';
            app.R2_minEditField.Tooltip = {'Every point fit must have a Rsq value higher than this.'; 'Rsq gives an idea of how good the fit is.'; 'Rsq = 1 means the fit represents 100% of the data.'; ''; 'By putting Rsq min = 0 no point is excluded, even the ones with terrible (Rsq ~ 0) fit.'};
            app.ProcessMapButton = uibutton(app.RightPanel, 'push');
            app.ProcessMapButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessMapButtonPushed, true);
            app.ProcessMapButton.Enable = 'off';
            app.ProcessMapButton.Tooltip = {'Start processing the curve map and show the elastic modulus'' map.'};
            app.ProcessMapButton.Text = 'Process map';
            app.LLabel = uilabel(app.RightPanel);
            app.LLabel.Interpreter = 'latex';
            app.LLabel.Text = '${\mu}m$';
            app.LEditFieldLabel = uilabel(app.RightPanel);
            app.LEditFieldLabel.Interpreter = 'latex';
            app.LEditFieldLabel.Text = '$L$';
            app.LEditField = uieditfield(app.RightPanel, 'numeric');
            app.LEditField.Limits = [0 Inf];
            app.LEditField.ValueDisplayFormat = '%.2f';
            app.LEditField.Tooltip = {'Map''s edge length in micrometers.'};
            app.LEditField.Value = 1;
            app.TitleEditFieldLabel = uilabel(app.RightPanel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.RightPanel, 'text');
            app.TitleEditField.Tooltip = {'Title assigned to the figure.'};
            app.TitleEditField.Placeholder = 'Insert map title...';
            app.HistogramOptionsLabel = uilabel(app.RightPanel);
            app.HistogramOptionsLabel.Text = '- Histogram options -';
            app.BinsEditFieldLabel = uilabel(app.RightPanel);
            app.BinsEditFieldLabel.Text = 'Bins';
            app.BinsEditField = uieditfield(app.RightPanel, 'numeric');
            app.BinsEditField.Limits = [1 Inf];
            app.BinsEditField.RoundFractionalValues = 'on';
            app.BinsEditField.ValueDisplayFormat = '%.0f';
            app.BinsEditField.Tooltip = {'Number of bins of the histogram.'};
            app.BinsEditField.Value = 10;
            app.FromEditField = uieditfield(app.RightPanel, 'numeric');
            app.FromEditField.Limits = [0 Inf];
            app.FromEditField.Tooltip = {'Number of bins of the histogram.'};
            app.FromEditField.Value = 10;
            app.FromLabel = uilabel(app.RightPanel);
            app.FromLabel.Text = 'From';
            app.toLabel = uilabel(app.RightPanel);
            app.toLabel.Text = 'to';
            app.ToEditField = uieditfield(app.RightPanel, 'numeric');
            app.ToEditField.Limits = [0 Inf];
            app.ToEditField.Tooltip = {'Number of bins of the histogram.'};
            app.ToEditField.Value = 10;
            app.PaLabel = uilabel(app.RightPanel);
            app.PaLabel.Interpreter = 'latex';
            app.PaLabel.Text = '$Pa$';
            app.GenerateHistogramButton = uibutton(app.RightPanel, 'push');
            app.GenerateHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateHistogramButtonPushed, true);
            app.GenerateHistogramButton.Enable = 'off';
            app.GenerateHistogramButton.Tooltip = {'Start processing the curve map and show the elastic modulus'' map.'};
            app.GenerateHistogramButton.Text = 'Generate histogram';
            app.SaveDataButton = uibutton(app.RightPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Icon = fullfile(pathToMLAPP, 'icons', 'save-icon.png');
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Tooltip = {'Save processed data as matrix in a custom file.'};
            app.SaveDataButton.Text = '';
            % Show the figure after all components are created
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = curves_map_analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CurvesMapAnalyzerbySavoldiTommasoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CurvesMapAnalyzerbySavoldiTommasoUIFigure)
        end
    end
end